classdef calibApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProCamCalibUIFigure             matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        CreatenewdatadirectoryMenu      matlab.ui.container.Menu
        ChangedatadirectryMenu          matlab.ui.container.Menu
        DatafolderButton                matlab.ui.control.Button
        DataDirectoryLabel              matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        CaptureTab                      matlab.ui.container.Tab
        CameraControlPanel              matlab.ui.container.Panel
        CaptureTypeButtonGroup          matlab.ui.container.ButtonGroup
        ReconButton                     matlab.ui.control.RadioButton
        CalibButton                     matlab.ui.control.RadioButton
        AdaptButton                     matlab.ui.control.Button
        CaptureButton                   matlab.ui.control.StateButton
        FocusModeDropDown               matlab.ui.control.DropDown
        FocusModeDropDownLabel          matlab.ui.control.Label
        ExposureTimesSlider             matlab.ui.control.Slider
        ExposureTimesSliderLabel        matlab.ui.control.Label
        BrightnessSliderLabel           matlab.ui.control.Label
        BrightnessSlider                matlab.ui.control.Slider
        BacklightCompensationDropDown   matlab.ui.control.DropDown
        BacklightCompensationDropDownLabel  matlab.ui.control.Label
        WhiteBalanceModeDropDown        matlab.ui.control.DropDown
        WhiteBalanceModeDropDownLabel   matlab.ui.control.Label
        ExposureModeDropDown            matlab.ui.control.DropDown
        ExposureModeDropDownLabel       matlab.ui.control.Label
        WhiteBalanceSlider              matlab.ui.control.Slider
        WhiteBalanceSliderLabel         matlab.ui.control.Label
        SharpnessSlider                 matlab.ui.control.Slider
        SharpnessSliderLabel            matlab.ui.control.Label
        SaturationSlider                matlab.ui.control.Slider
        SaturationSliderLabel           matlab.ui.control.Label
        HueSlider                       matlab.ui.control.Slider
        HueSliderLabel                  matlab.ui.control.Label
        GammaSlider                     matlab.ui.control.Slider
        GammaSliderLabel                matlab.ui.control.Label
        GainSlider                      matlab.ui.control.Slider
        GainSliderLabel                 matlab.ui.control.Label
        ExposureSlider                  matlab.ui.control.Slider
        ExposureSliderLabel             matlab.ui.control.Label
        ContrastSlider                  matlab.ui.control.Slider
        ContrastSliderLabel             matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        ResolutionLabel                 matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        WidthEditField_2Label           matlab.ui.control.Label
        HeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        BufferSizeSpinnerLabel          matlab.ui.control.Label
        BufferSizeSpinner               matlab.ui.control.Spinner
        CamPreviewButton                matlab.ui.control.StateButton
        ResolutionDropDown              matlab.ui.control.DropDown
        CameraNameDropDown              matlab.ui.control.DropDown
        CameraNameDropDownLabel         matlab.ui.control.Label
        ProjectorControlPanel           matlab.ui.container.Panel
        horicolorsEditField             matlab.ui.control.NumericEditField
        horicolorsEditFieldLabel        matlab.ui.control.Label
        vertcolorsEditField             matlab.ui.control.NumericEditField
        vertcolorsEditFieldLabel        matlab.ui.control.Label
        InputPatternLabel               matlab.ui.control.Label
        ProjectorPreviewButton          matlab.ui.control.StateButton
        IndexSpinnerLabel               matlab.ui.control.Label
        IndexSpinner                    matlab.ui.control.Spinner
        InputPatternTree                matlab.ui.container.CheckBoxTree
        gridNode                        matlab.ui.container.TreeNode
        whiteNode                       matlab.ui.container.TreeNode
        PrjBrightnessSlider             matlab.ui.control.Slider
        BrightnessSlider_2Label         matlab.ui.control.Label
        ProjectorDisplayLabel           matlab.ui.control.Label
        ProjectorDisplayDropDown        matlab.ui.control.DropDown
        PreviewPanelCapture             matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        CamSceneImg                     matlab.ui.control.Image
        CameracapturedProjectionLabel   matlab.ui.control.Label
        SceneLabel                      matlab.ui.control.Label
        ProjectorInputLabel             matlab.ui.control.Label
        CamCapPrjImg                    matlab.ui.control.Image
        PrjInputImg                     matlab.ui.control.Image
        CalibrationReconstructionTab    matlab.ui.container.Tab
        PlotExtrinsicsButton            matlab.ui.control.Button
        PlotErrorsButton                matlab.ui.control.Button
        CalibrationResultsTextArea      matlab.ui.control.TextArea
        CalibrationResultsTextAreaLabel  matlab.ui.control.Label
        PreviewPanelCalibration         matlab.ui.container.Panel
        CalibImageTypeButtonGroup       matlab.ui.container.ButtonGroup
        ManualSegButton                 matlab.ui.control.Button
        AutoSegButton                   matlab.ui.control.Button
        colorGridButton                 matlab.ui.control.RadioButton
        sceneButton                     matlab.ui.control.RadioButton
        PreviewImage                    matlab.ui.control.Image
        LoadCalibrationButton           matlab.ui.control.Button
        CalibrationPanel                matlab.ui.container.Panel
        CheckerboardSquareSizeEditFieldLabel  matlab.ui.control.Label
        CheckerboardSquareSizeEditField  matlab.ui.control.NumericEditField
        CheckerboardSquareSizeEditFieldLabel_2  matlab.ui.control.Label
        CalibrateButton                 matlab.ui.control.Button
        VerbosedebugCheckBox            matlab.ui.control.CheckBox
        UseExistingCamCornersCheckBox   matlab.ui.control.CheckBox
        UseParallelCheckBox             matlab.ui.control.CheckBox
        EstimateLensDistortionCheckBox  matlab.ui.control.CheckBox
        DeleteButton                    matlab.ui.control.Button
        SelectAllButton                 matlab.ui.control.Button
        CalibImageListBox               matlab.ui.control.ListBox
        ReconstructionPanel             matlab.ui.container.Panel
        DeleteReconButton               matlab.ui.control.Button
        ReconImageTree                  matlab.ui.container.Tree
        InterpolatePointCloudCheckBox   matlab.ui.control.CheckBox
        DirectLightThreshmEditFieldLabel  matlab.ui.control.Label
        DirectLightThreshmEditField     matlab.ui.control.NumericEditField
        ProjectorbacklightstengthbEditFieldLabel  matlab.ui.control.Label
        ProjectorbacklightstengthbEditField  matlab.ui.control.NumericEditField
        MinmaxContrastThreshtEditFieldLabel  matlab.ui.control.Label
        MinmaxContrastThreshtEditField  matlab.ui.control.NumericEditField
        ReconstructButton               matlab.ui.control.Button
        EpipolarThreshpixelEditFieldLabel  matlab.ui.control.Label
        EpipolarThreshpixelEditField    matlab.ui.control.NumericEditField
        ReconstructSigleshotButton      matlab.ui.control.Button
        UseExistingMaskCheckBox         matlab.ui.control.CheckBox
        ReconVerbosedebugCheckBox       matlab.ui.control.CheckBox
        UseEdgePixelsCheckBox           matlab.ui.control.CheckBox
        AboutTab                        matlab.ui.container.Tab
        GitHubHyperlink                 matlab.ui.control.Hyperlink
        ConfLabel                       matlab.ui.control.Label
        ContextMenu                     matlab.ui.container.ContextMenu
        DeleteMenu                      matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        OpenMenu                        matlab.ui.container.Menu
    end

    %% License
    % ACADEMIC OR NON-PROFIT ORGANIZATION NONCOMMERCIAL RESEARCH USE ONLY
    % Copyright (c) 2018 Bingyao Huang
    % All rights reserved.

    % Redistribution and use in source and binary forms, with or without
    % modification, are permitted provided that the following conditions are met:

    % The above copyright notice and this permission notice shall be included in all
    % copies or substantial portions of the Software.
    %
    % If you publish results obtained using this software, please cite our paper.

    % THE SOFTWARE IS PROVIDED "AS IS", WITHOUT WARRANTY OF ANY KIND, EXPRESS OR
    % IMPLIED, INCLUDING BUT NOT LIMITED TO THE WARRANTIES OF MERCHANTABILITY,
    % FITNESS FOR A PARTICULAR PURPOSE AND NONINFRINGEMENT. IN NO EVENT SHALL THE
    % AUTHORS OR COPYRIGHT HOLDERS BE LIABLE FOR ANY CLAIM, DAMAGES OR OTHER
    % LIABILITY, WHETHER IN AN ACTION OF CONTRACT, TORT OR OTHERWISE, ARISING FROM,
    % OUT OF OR IN CONNECTION WITH THE SOFTWARE OR THE USE OR OTHER DEALINGS IN THE
    % SOFTWARE.
    properties (Access = private)
        % camera properties
%         cam % webcam object used to capture image
        camList % a list of connected cameras that are recognized by matlab
        camInfo % all cameras' properties, such as birghtnees, exposure etc.
        camSliderProperties
        camDropDownProperties
        camPreviewImg % screenshot of the camera image
        %         camPreviewFig % camera preivew figure
        camExposureTime % camera exposure time
%         camImgSize % saved camera-captured image size specified by user


        % projector properties
        prjColorGrid
        prjGrayPattern
        screenSize
        prjFigHandel
        %         prjBrightness = 1.0
        %         imgCounter = 1 % count how many images captured so far

        previewCalibImageType = 'scene' % Description
    end

    properties (Access = public)
        cam % webcam object used to capture image
        camImgSize % saved camera-captured image size specified by user
        capturing % a flag indicating whether the camera is capturing
        cmpCapTypes = {'ref', 'train', 'test', 'sl', 'cb', 'mov', 'cmp_test', 'cmp_mov'};
        % calibration data root and folder name
%         dataRoot = fullfile(pwd, 'data')
        dataRoot = 0 % to be compatible with uigetdir when cancel clicked
        dataName
        calibInfo % calibration info to be saved to file
        calibOption % calibration options to pass to calibrateGUI
        reconOption % reconstruction options to pass to calibrateGUI
        stereoParams % calibrated/loaded stereo parameters
        logDir % log directory
        numColor % the number of colors for pattern
    end

    methods (Access = public)

        function initCameras(app)
            %             camList = imaqhwinfo('winvideo'); % windows
            %             camList = imaqhwinfo('macvideo'); % linux
            %             camList = imaqhwinfo('linuxvideo'); % mac

            %             for i = numel(camList)
            %                 curDevice = imaqhwinfo(adaptorList.InstalledAdaptors{i});
            %
            %             end

            app.camList = webcamlist();

            % error if camera not found
            if(numel(app.camList) == 0)
                uialert(app.ProCamCalibUIFigure, 'Camera not found','Error');
            else
                app.CameraNameDropDown.Items = app.camList;

                % controllable camea properties
                app.camSliderProperties = {'Brightness', 'Contrast', 'Exposure', 'Gain', 'Gamma', 'Hue', 'Saturation', 'Sharpness', 'WhiteBalance'};
                app.camDropDownProperties = {'BacklightCompensation', 'ExposureMode', 'WhiteBalanceMode', 'FocusMode'};
                %                 app.CameraAdjusmentPanel.Visible = false;
                %                 app.camExposureTime = app.ExposureTimesSlider.Value;
                app.camImgSize = [app.HeightEditField.Value, app.WidthEditField.Value]; % camera captured image size

                % get camera infos, such as supported register values
                %                 getCamInfo(app);
                % display camera names in dropdown list
                %                 app.CameraNameDropDown.Items = {camList.DeviceInfo.DeviceName};
                %                 app.CameraNameDropDown.ItemsData = 1:numel(app.CameraNameDropDown.Items);
                %                 app.CameraNameDropDown.Items = {camList.DeviceInfo.DeviceName};

                %                 app.CameraNameDropDown.Value = camList{1};

                % start cam
                %                 app.cam = webcam(app.camList{1});
                %                 updateCamResolutionDropDown(app);
                %                 updateCamControlPannel(app, 1);
                %
                %                 % open cam preview window
                %                 app.camPreviewImg = preview(app.cam);
            end

        end

        %% update the camera resolution dropdown list
        function updateCamResolutionDropDown(app)
            app.ResolutionDropDown.Items = app.cam.AvailableResolutions;
            app.ResolutionDropDown.Value = app.cam.Resolution;
        end

        %         function getCamInfo(app, camID)

        %             f = waitbar(0, 'Detecting connected webcams...');
        %             for camID = 1:numel(app.camList)
        %                 waitbar(camID/numel(app.camList), f, [num2str(camID), ' webcam discovered...']);
        %                 src = getselectedsource(videoinput('winvideo', camID)); % slow
        %                 app.camInfo{camID} = src.propinfo;

        %                 getCamPropertyConstraint(app, camID);
        %             end

        %             close(f);
        %         end

        function updateCamControlPannel(app, camID)
            initCamSliders(app, camID);
            initCamDropDowns(app, camID);
            resetCamDropDowns(app, camID);
        end

        % get screen res
        function getScreenInfo(app)
            app.screenSize = round(get(0, 'MonitorPositions'));
            numMonitors = size(app.screenSize, 1);

            for i=1:numMonitors
                app.ProjectorDisplayDropDown.Items{i} = ['[Screen: ', num2str(i), '] ', num2str(app.screenSize(i,3)), 'x', num2str(app.screenSize(i,4))];
            end

            app.ProjectorDisplayDropDown.ItemsData = 1:numMonitors;

            % init projector image
%             updatePrjPreviewImg(app);
        end

        % initialize a projector window
        function figHandle = initPrjWindow(app, screenID, im)
            %             app.prjColorGrid = ImgProc.genStructuredLight(app.screenSize(screenID, 3), app.screenSize(screenID, 4));
            figHandle = figure('MenuBar', 'none', 'ToolBar', 'none', 'color', [0,0,0]);
            imshow(im);
            axis off

            axeHandle = gca;
            set(axeHandle, 'Position', [0 0 1 1])

            set(figHandle, 'Position', app.screenSize(screenID,:))
            set(figHandle, 'WindowState', 'fullscreen')

            % speedup projection
            set(gca, 'xlimmode','manual',...
                'ylimmode','manual',...
                'zlimmode','manual',...
                'climmode','manual',...
                'alimmode','manual');
            set(figHandle,'doublebuffer','off');
        end
        
        % project image using the projector
        function projectImage(app, im)
            screenID = app.ProjectorDisplayDropDown.Value;

            if(isempty(app.prjFigHandel) || ~isvalid(app.prjFigHandel))
                app.prjFigHandel = initPrjWindow(app, screenID, im);
                app.prjFigHandel.DeleteFcn = createCallbackFcn(app, @disablePreviewPrjStateBtn, true); % set preview state button to disable when window is closed
                drawnow;
            else
                %                 cla;
                %                 set(app.prjFigHandel, 'WindowState', 'normal');
                %                 imshow(im);
                %
                %                 axisHandle = gca;
                %                 set(axisHandle, 'Position', [0 0 1 1]);
                %
                %                 set(app.prjFigHandel, 'Position', app.screenSize(screenID,:))
                %                 set(app.prjFigHandel, 'WindowState', 'fullscreen');
                app.prjFigHandel.Children.Children.CData = im;
%                 app.PrjInputImg.ImageSource = im;
                
                drawnow
            end
            % set projector preview window as enabled
            app.ProjectorPreviewButton.Value = 1;
            app.PrjInputImg.ImageSource = im;
        end
        
        % clear camera buffer to avoid unsynced projector and camera frames
        function clearCamBuffer(app)
            for i = 1:app.BufferSizeSpinner.Value
                app.cam.snapshot;
            end
        end
        
        % update calibration results and display
        function updateCalibrationResultText(app, calibFileName, stereoParams)
            app.CalibrationResultsTextArea.Value = {
                sprintf('File name:     %s\n\t', calibFileName);
                '----------------------- Intrinsics ---------------------'
                sprintf('Camera projection matrix (camK):\n[%12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f]\n\t', reshape(stereoParams.camK', 1, 9));
                sprintf('Camera distortion coefficients (camKc):\n[%12.3f, %12.3f, %12.3f %12.3f]\n\t', stereoParams.camKc(:));
                sprintf('Projector projection matrix (prjK):\n[%12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f]\n\t', reshape(stereoParams.prjK', 1, 9));
                sprintf('Projector distortion coefficients (prjKc):\n[%12.3f, %12.3f, %12.3f %12.3f]\n\t', stereoParams.prjKc(:));
                '----------------------- Extrinsics ---------------------'
                sprintf('Rotation matrix (R):\n[%12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f]\n\t', reshape(stereoParams.R', 1, 9));
                sprintf('Translation vector (T):\n[%12.3f, %12.3f, %12.3f]\n\t', stereoParams.T(:));
                sprintf('Essential matrix (E):\n[%12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f]\n\t', reshape(stereoParams.E', 1, 9));
                sprintf('Fundamental matrix (F):\n[%12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f\n %12.3f, %12.3f, %12.3f]\n\t', reshape(stereoParams.F', 1, 9));
                '------------------------ Errors ------------------------'
                sprintf('Camera reprojection error:\n%5.3f\n\t', stereoParams.camReprojErr);
                sprintf('Projector reprojection error:\n%5.3f\n\t', stereoParams.prjReprojErr);
                sprintf('Stereo reprojection error:\n%5.3f\n\t', stereoParams.stereoReprojErr);
                sprintf('Camera per-view reprojection error:\n[%s]\n\t', strjoin(string(stereoParams.camPerViewReprojErr), ', '));
                sprintf('Projector per-view reprojection error:\n[%s]\n\t', strjoin(string(stereoParams.prjPerViewReprojErr), ', '));
                sprintf('Stereo per-view reprojection error:\n[%s]', strjoin(string(stereoParams.stereoPerViewReprojErr), ', '));
                };
        end
        
%         function makeMasksToCalClassificationAcc(app)
%             if(~ischar(app.dataRoot))
%                 app.CreatenewdatadirectryMenuSelected() % use this function to browse a dir to save captured data
%             end
%             
%             % if user clicked cancel in uigetdir dialog
%             if(app.dataRoot) % if(~app.dataRoot) may cause errors
%                 dataFolder = fullfile(app.dataRoot, app.dataName);
%             else
%                 uialert(app.ProCamCalibUIFigure, 'No data root dir selected, capture stopped.', 'Warning')
%                 return;
%             end
%             
%             maskFolder = Utils.mkdirs(fullfile(dataFolder, 'mask'));
%             
%             % find camera
%             camName = app.CameraNameDropDown.Value;
%             if(isempty(camName))
%                 uialert(app.ProCamCalibUIFigure, 'Camera not found','Error');
%                 return;
%             end
%             
%             % start cam preview
%             if(isempty(app.camPreviewImg))
%                 app.CamPreviewButton.Value = 1; % enable preview
%                 app.CamPreviewButtonValueChanged();
%             end
%             
%             % start projection
%             screenID = app.ProjectorDisplayDropDown.Value;
%             screenH = app.screenSize(screenID, 4);
%             screenW = app.screenSize(screenID, 3);
%             
%             prjW = screenW;
%             prjH = screenH;
%             
%             % capType = app.CaptureTypeButtonGroup.SelectedObject.Text;            
%             % imBlack = zeros(screenW, screenH, 3); % in case desktop provides some lighting, we cover the fullscreen with a black image
%             imBlack = zeros(screenH, screenW, 3); % in case desktop provides some lighting, we cover the fullscreen with a black image
%             projectImage(app, imBlack);
%             
%             % larger camExposureTime and CamBufferSpinner.Value should have larger exposureTime, if you see partial color grid (due to insufficient expoure time), increase exposureTime.
%             % clear buffer
%             pause(app.ExposureTimesSlider.Value);
%             clearCamBuffer(app);
%             imScene = getFrame(app);
%             
%             
%             % project white grid and capture imWhiteGrid
%             % app.prjColorGrid = ImgProc.genStructuredLight(screenH, screenW);
%             app.prjColorGrid = AdaptiveGenPattern.genWhiteLight(prjW, prjH);
%             projectImage(app, app.prjColorGrid * app.PrjBrightnessSlider.Value);
%             
%             % clear buffer
%             pause(app.ExposureTimesSlider.Value);
%             clearCamBuffer(app);
%             imWhiteGrid = getFrame(app);
%             
%             % preview
%             app.CamSceneImg.ImageSource = imScene;
%             app.CamCapPrjImg.ImageSource = imWhiteGrid;
%             
%             % check if the two images are similar (due to camera's slow buffer or small exposure time, increase buffer count or exposure time)
% %             if(immse(im2double(imScene), im2double(imWhiteGrid)) < 0.001)
% %                 uialert(app.ProCamCalibUIFigure, 'Seems the camera light image is the same as color grid image, try a longer Exposure Time or larger buffer count and recapture','Error');
% %                 return;
% %             end
%             
%             updateCalibImageList(app);  
%             
%             % record all single color images
%             [imColor(1:8).image] = deal([]);
%             
%             for i = 1: 8
%                 if(rem(i,2) ~= 0)
%                     colorFlag = strcat('H',num2str((i+1)/2)); 
%                 else
%                     colorFlag = strcat('V',num2str(i/2)); 
%                 end
%                 
%                 % project white grid and capture imWhiteGrid
%                 % app.prjColorGrid = ImgProc.genStructuredLight(screenH, screenW);
%                 app.prjColorGrid = AdaptiveGenPattern.genSingleColorPatternToMakeMasks(prjW, prjH, colorFlag);
%                 projectImage(app, app.prjColorGrid * app.PrjBrightnessSlider.Value);
%             
%                 % clear buffer
%                 pause(app.ExposureTimesSlider.Value);
%                 clearCamBuffer(app);
%                 imSingleColor = getFrame(app);
%                 
%                 % preview
%                 app.CamSceneImg.ImageSource = imScene;
%                 app.CamCapPrjImg.ImageSource = imSingleColor;
%             
%                 % check if the two images are similar (due to camera's slow buffer or small exposure time, increase buffer count or exposure time)
% %                 if(immse(im2double(imScene), im2double(imSingleColor)) < 0.0001)
% %                     uialert(app.ProCamCalibUIFigure, 'Seems the camera light image is the same as color grid image, try a longer Exposure Time or larger buffer count and recapture','Error');
% %                     return;
% %                 end
%             
%                 imColor(i).image = imSingleColor;
%             
%                 updateCalibImageList(app);          
%            end
%         
%         % segment single color grid
%         [imColor1HoriBWGrid, imColor1VertBWGrid, imColor2HoriBWGrid, imColor2VertBWGrid,imColor3HoriBWGrid, imColor3VertBWGrid,imColor4HoriBWGrid, imColor4VertBWGrid] = AdaptiveGenPattern.genMasksToMakeMasks(imScene, imWhiteGrid, imColor);
%         
%         % if empty sets
%         if(numel(app.CalibImageListBox.Items) == 0)
%             missingIdx = [1];
%         else
%             % check if some sets were missing, if so fill them using the newly captured images
%             missingIdx = findMissingSetIdx(app, app.CalibImageListBox.Items);
%         end
%         
%         miss = (missingIdx(1)+1)/2;
%         
%         % save captured images
%         cv.imwrite(fullfile(maskFolder, ['imColor1HoriBWGrid', sprintf('%02d', miss), '.png']), im2uint8(imColor1HoriBWGrid));
%         cv.imwrite(fullfile(maskFolder, ['imColor1VertBWGrid', sprintf('%02d', miss), '.png']), im2uint8(imColor1VertBWGrid));
%         cv.imwrite(fullfile(maskFolder, ['imColor2HoriBWGrid', sprintf('%02d', miss), '.png']), im2uint8(imColor2HoriBWGrid));
%         cv.imwrite(fullfile(maskFolder, ['imColor2VertBWGrid', sprintf('%02d', miss), '.png']), im2uint8(imColor2VertBWGrid));
%         cv.imwrite(fullfile(maskFolder, ['imColor3HoriBWGrid', sprintf('%02d', miss), '.png']), im2uint8(imColor3HoriBWGrid));
%         cv.imwrite(fullfile(maskFolder, ['imColor3VertBWGrid', sprintf('%02d', miss), '.png']), im2uint8(imColor3VertBWGrid));
%         cv.imwrite(fullfile(maskFolder, ['imColor4HoriBWGrid', sprintf('%02d', miss), '.png']), im2uint8(imColor4HoriBWGrid));
%         cv.imwrite(fullfile(maskFolder, ['imColor4VertBWGrid', sprintf('%02d', miss), '.png']), im2uint8(imColor4VertBWGrid));
%         
%         disp(['---------------Finish-------------'])
%         end
        
        
        function genAdaptivePattern(app)
            % if first time to click "capture", browse for a new dataRoot
            if(~ischar(app.dataRoot))
                app.CreatenewdatadirectryMenuSelected() % use this function to browse a dir to save captured data
            end
            
            % if user clicked cancel in uigetdir dialog
            if(app.dataRoot) % if(~app.dataRoot) may cause errors
                dataFolder = fullfile(app.dataRoot, app.dataName);
            else
                uialert(app.ProCamCalibUIFigure, 'No data root dir selected, capture stopped.', 'Warning')
                return;
            end
            
            % create folder
            adapFolder = Utils.mkdirs(fullfile(dataFolder, 'adap'));
            adapDataFolder = Utils.mkdirs(fullfile(dataFolder, 'adap', 'data'));
            
            % find camera
            camName = app.CameraNameDropDown.Value;
            if(isempty(camName))
                uialert(app.ProCamCalibUIFigure, 'Camera not found','Error');
                return;
            end
            
            % start cam preview
            if(isempty(app.camPreviewImg))
                app.CamPreviewButton.Value = 1; % enable preview
                app.CamPreviewButtonValueChanged();
            end

            % start projection
            screenID = app.ProjectorDisplayDropDown.Value;
            screenH = app.screenSize(screenID, 4);
            screenW = app.screenSize(screenID, 3);
            
            prjW = screenW;
            prjH = screenH;
            
            % capType = app.CaptureTypeButtonGroup.SelectedObject.Text;            
            % imBlack = zeros(screenW, screenH, 3); % in case desktop provides some lighting, we cover the fullscreen with a black image
            imBlack = zeros(screenH, screenW, 3); % in case desktop provides some lighting, we cover the fullscreen with a black image
            projectImage(app, imBlack);

            % larger camExposureTime and CamBufferSpinner.Value should have larger exposureTime, if you see partial color grid (due to insufficient expoure time), increase exposureTime.
            % clear buffer
            pause(app.ExposureTimesSlider.Value);
            clearCamBuffer(app);
            imScene = getFrame(app);
            
            % save scene image
            cv.imwrite(fullfile(adapDataFolder, ['scene.png']), imScene);
            
            [imSingleColorTotal(1:4).image] = deal([]);
            
            for i = 1:4
                colorFlag = strcat('c',num2str(i));
                
                % project white grid and capture imWhiteGrid
                % app.prjColorGrid = ImgProc.genStructuredLight(screenH, screenW);
                app.prjColorGrid = AdaptiveGenPattern.genSingleColorPattern(prjW, prjH, colorFlag);
                projectImage(app, app.prjColorGrid * app.PrjBrightnessSlider.Value);
    
                % clear buffer
                pause(app.ExposureTimesSlider.Value);
                clearCamBuffer(app);
                imSingleColor = getFrame(app);
    
                % preview
                app.CamSceneImg.ImageSource = imScene;
                app.CamCapPrjImg.ImageSource = imSingleColor;
    
                % check if the two images are similar (due to camera's slow buffer or small exposure time, increase buffer count or exposure time)
%                 if(immse(im2double(imScene), im2double(imSingleColor)) < 0.001)
%                     uialert(app.ProCamCalibUIFigure, 'Seems the camera light image is the same as color grid image, try a longer Exposure Time or larger buffer count and recapture','Error');
%                     return;
%                 end
                
                % save image
                cv.imwrite(fullfile(adapDataFolder, ['singleColor', sprintf('%02d', i), '.png']), imSingleColor);
                imSingleColorTotal(i).image = imSingleColor;
                
                updateCalibImageList(app);          
            end
            
            % generate masks for different colors
            [imMask1, imMask2, imMask3, imMask4, imBWBoardMask, imBWCb] = AdaptiveGenPattern.genMasks(imScene, imSingleColorTotal);
            
            % iteration termination flag
            iterativeFlag = 0;
            
            % the threshold for loss
            thresholdLoss = 0.05;
            
            % initialize hue
            curHue = [0,0.25,0.5,0.75];
            
            % initialize loss, use loss to represent the score in our paper and score = 6 - loss 
            optimalLoss = 6;
            optimaIColorInfo = [];
            
            % the number of colors
            app.numColor = [app.horicolorsEditField.Value, app.vertcolorsEditField.Value];
            numHoriColor = app.numColor(1);
            numVertColor = app.numColor(2);
            
            % record hue, variance and vector of hue for different colors
            [recordedColorInfo(1:200).hue] = deal([]);
            [recordedColorInfo(1:200).variance] = deal([]);
            [recordedColorInfo(1:200).vecHue] = deal([]);
            
            recordCount = 1;
            
            %%%%%%%%%%%%% First stage %%%%%%%%%%%%%%%%%%%%%%%%
            % record the number of iterations
            i = 1;
            
            % unitization option
            unitizationOption = 1;
             
            while(iterativeFlag == 0 && i<=5)
                % normalize hue to [0, 1]
                % curHue = AdaptiveGenPattern.normalizeHue(curHue);   
                
                % generate hsv: set saturation and value to 1
                curHsv = [curHue(1), 1, 1; curHue(1)  1  1; curHue(2), 1, 1; curHue(2)  1  1;curHue(3), 1, 1; curHue(3)  1  1;curHue(4), 1, 1; curHue(4)  1  1];
                
                %project pattern image and capture color grid image
                % project white grid and capture imWhiteGrid
                % app.prjColorGrid = ImgProc.genStructuredLight(screenH, screenW);
                app.prjColorGrid = AdaptiveGenPattern.genAdapStructuredLight(prjW, prjH,curHsv);
                projectImage(app, app.prjColorGrid * app.PrjBrightnessSlider.Value);
    
                % clear buffer
                pause(app.ExposureTimesSlider.Value);
                clearCamBuffer(app);
                imColorGrid = getFrame(app);
    
                % preview
                app.CamSceneImg.ImageSource = imScene;
                app.CamCapPrjImg.ImageSource = imColorGrid;
    
                % check if the two images are similar (due to camera's slow buffer or small exposure time, increase buffer count or exposure time)
%                 if(immse(im2double(imScene), im2double(imColorGrid)) < 0.001)
%                     uialert(app.ProCamCalibUIFigure, 'Seems the camera light image is the same as color grid image, try a longer Exposure Time or larger buffer count and recapture','Error');
%                     return;
%                 end
                
                cv.imwrite(fullfile(adapDataFolder, ['colorGrid', sprintf('%02d', i), '.png']), imColorGrid);
                
                updateCalibImageList(app);

                % get image masked
                [imColor1GridMasked, imColor2GridMasked, imColor3GridMasked, imColor4GridMasked] = AdaptiveGenPattern.getSingleColorGrid(imScene, imColorGrid, imMask1,imMask2,imMask3,imMask4,imBWBoardMask);
                
                % segment color grid to test the result of segmentation
                imCurrBWGrid = AdaptiveGenPattern.testSegColorGrid(imScene, imColorGrid, imBWBoardMask, imBWCb);
                
                % record hue, vecHue and variance for different colors
                curColorInfo(1).hue = curHue(1);
                curColorInfo(1).vecHue = AdaptiveGenPattern.calcVecHue(imColor1GridMasked);
                curColorInfo(1).histHue = AdaptiveGenPattern.calcHistHue(curColorInfo(1).vecHue, unitizationOption);
                curColorInfo(1).jugValue = AdaptiveGenPattern.calcSegSuccessRate(imCurrBWGrid, imMask1);
                curColorInfo(1).variance = var(curColorInfo(1).vecHue);
            
                curColorInfo(2).hue = curHue(2);
                curColorInfo(2).vecHue = AdaptiveGenPattern.calcVecHue(imColor2GridMasked);
                curColorInfo(2).histHue = AdaptiveGenPattern.calcHistHue(curColorInfo(2).vecHue, unitizationOption);
                curColorInfo(2).jugValue = AdaptiveGenPattern.calcSegSuccessRate(imCurrBWGrid, imMask2);
                curColorInfo(2).variance = var(curColorInfo(2).vecHue);
            
                curColorInfo(3).hue = curHue(3);
                curColorInfo(3).vecHue = AdaptiveGenPattern.calcVecHue(imColor3GridMasked);
                curColorInfo(3).histHue = AdaptiveGenPattern.calcHistHue(curColorInfo(3).vecHue, unitizationOption);
                curColorInfo(3).jugValue = AdaptiveGenPattern.calcSegSuccessRate(imCurrBWGrid, imMask3);
                curColorInfo(3).variance = var(curColorInfo(3).vecHue);
            
                curColorInfo(4).hue = curHue(4);
                curColorInfo(4).vecHue = AdaptiveGenPattern.calcVecHue(imColor4GridMasked);
                curColorInfo(4).histHue = AdaptiveGenPattern.calcHistHue(curColorInfo(4).vecHue, unitizationOption);
                curColorInfo(4).jugValue = AdaptiveGenPattern.calcSegSuccessRate(imCurrBWGrid, imMask4);
                curColorInfo(4).variance = var(curColorInfo(4).vecHue);
                
                % get hue histogram
                histHue1 = curColorInfo(1).histHue;
                histHue2 = curColorInfo(2).histHue;
                histHue3 = curColorInfo(3).histHue;
                histHue4 = curColorInfo(4).histHue;
                
                
                % get loss of every two hue distribution
                loss1 = AdaptiveGenPattern.calcColorDistinction(histHue1, histHue2);
                loss2 = AdaptiveGenPattern.calcColorDistinction(histHue1, histHue3);
                loss3 = AdaptiveGenPattern.calcColorDistinction(histHue1, histHue4);
                loss4 = AdaptiveGenPattern.calcColorDistinction(histHue2, histHue3);
                loss5 = AdaptiveGenPattern.calcColorDistinction(histHue2, histHue4);
                loss6 = AdaptiveGenPattern.calcColorDistinction(histHue3, histHue4);
                
                % calculate loss for current color combination
                curLoss = sum([loss1,loss2,loss3,loss4,loss5,loss6]);
                
                % record all hue, hue vector and variance
                hueList = [recordedColorInfo(:).hue];
                for j=1:4
                    % only record color information that reaches the segmentation success rate threshold
                    if(~ismember(curColorInfo(j).hue,hueList) && curColorInfo(j).jugValue == 1)
                        recordedColorInfo(recordCount).hue = curColorInfo(j).hue;
                        recordedColorInfo(recordCount).variance = curColorInfo(j).variance;
                        recordedColorInfo(recordCount).vecHue = curColorInfo(j).vecHue;
                        recordedColorInfo(recordCount).histHue = curColorInfo(j).histHue;
                        
                        recordCount = recordCount + 1; 
                    end    
                end
                
                % display loss
                disp([strcat('Hue:', num2str(curHue))]);
                disp([strcat('Loss: ',num2str(curLoss))]);
                disp([strcat('minLoss: ',num2str(optimalLoss))]);
                disp(['-----------------------------------------------------------------']);
                
                % save optimal solution
                vecJudge = [curColorInfo(:).jugValue];
                if(curLoss < optimalLoss && isempty(find(vecJudge==0)) && numHoriColor == 4 && numVertColor == 4)
                    optimalLoss = curLoss;
                    optimaIColorInfo = curColorInfo;
                end
                
                % the condition of iterative termination
                if(optimalLoss < thresholdLoss && numHoriColor == 4 && numVertColor == 4)
                    iterativeFlag = 1;
                else
                    % continue next iteration
                    curHue = curHue + [0.05,0.05,0.05,0.05];
                    i = i+1;
                end     
            end
            
            
            %%%%%%%%%%%%% Second stage: get optimal solution uisng GA through combining stripes of different colors without considering stripe-to-stripe effects %%%%%%%
            if(iterativeFlag == 0)
                if(numHoriColor == numVertColor)
                    % if the number of horizontal and vertical colors is the same, only need to calculate an optimal set of color combination
                    [curIdx, curLoss] = AdaptiveGenPattern.calcOptimalColorComb(recordedColorInfo, recordCount, numHoriColor);
                    close
                    
                    % combineHue = [recordedColorInfo(combineIdx).hue];
                    disp([strcat('cur optimal loss:',num2str(curLoss))]);
                
                    % update optimal result
                    if(curLoss < optimalLoss)
                        optimalLoss = curLoss;
                        optimaIColorInfo = recordedColorInfo(curIdx);
                    end       
                else
                    % calculate an optimal set of color combination for
                    % horizontal colors
                    [curHoriIdx, curHoriLoss] = AdaptiveGenPattern.calcOptimalColorComb(recordedColorInfo, recordCount, numHoriColor);
                    close
                    
                    % calculate an optimal set of color combination for
                    % vertical colors
                    [curVertIdx, curVertLoss] = AdaptiveGenPattern.calcOptimalColorComb(recordedColorInfo, recordCount, numVertColor);
                    close
                       
                    % combineHue = [recordedColorInfo(combineIdx).hue];
                    disp([strcat('Current horizontal loss:', num2str(curHoriLoss))]);
                    disp([strcat('Current vertical loss:', num2str(curVertLoss))]);
                    
                    % update optimal result
                    if(curHoriLoss < optimalLoss && curVertLoss < optimalLoss)
                        optimalLoss = max(curHoriLoss, curVertLoss);
                        optimalHorColorInfo = recordedColorInfo(curHoriIdx);
                        optimalVertColorInfo = recordedColorInfo(curVertIdx);
                    end       
                end          
            end
           
            disp([strcat('Optimal loss: ',num2str(optimalLoss))]);
           
            if(numHoriColor == numVertColor)
                curNumColor = numHoriColor;
                
                % get color label
                originColorLabel = AdaptiveGenPattern.getHueLabel(optimaIColorInfo, curNumColor);
                hueLabel(:,:,1) = originColorLabel;
                hueLabel(:,:,2) = originColorLabel;
                
                % get hsv for pattern colors
                optimalHue = [optimaIColorInfo(:).hue];
                outputHue = zeros([2*curNumColor,1]);
                
                for i=1:curNumColor
                    outputHue(2*i-1) = optimalHue(i);
                    outputHue(2*i) = optimalHue(i);
                end
                
                % set saturation and value to 1
                currCompHsv = outputHue;
                currCompHsv(:,2:3) = deal(1);
            else
                % get color label
                colorHoriLabel = AdaptiveGenPattern.getHueLabel(optimalHorColorInfo, numHoriColor);
                colorVertLabel = AdaptiveGenPattern.getHueLabel(optimalVertColorInfo, numVertColor);
                
                hueLabel(:,:,1) = colorHoriLabel;
                hueLabel(:,:,2) = colorVertLabel;
                
                % get hsv for pattern colors
                optimalHoriHue = [optimalHorColorInfo(:).hue];
                optimalVertHue = [optimalVertColorInfo(:).hue];
                
                outputHue = zeros([2*max(numHoriColor, numVertColor), 1]);
                
                for i = 1:numHoriColor
                    outputHue(2*i-1) = optimalHoriHue(i);
                end
                
                for i = 1:numVertColor
                    outputHue(2*i) = optimalVertHue(i);
                end
                
                % set saturation and value to 1
                currCompHsv = outputHue;
                currCompHsv(:,2:3) = deal(1);
            end
                
            save(fullfile(adapFolder,  ['hueLabel.mat']), 'hueLabel');
            save(fullfile(adapFolder,  ['currCompHsv.mat']), 'currCompHsv');
                
            %% test the new pattern
            % project pattern image and capture color grid image
            % project white grid and capture imWhiteGrid
            % app.prjColorGrid = ImgProc.genStructuredLight(screenH, screenW);
            
            app.prjColorGrid = ImgProc.genStructuredLight(prjW, prjH, 1, currCompHsv, [numHoriColor, numVertColor]);
            projectImage(app, app.prjColorGrid * app.PrjBrightnessSlider.Value);

            % clear buffer
            pause(app.ExposureTimesSlider.Value);
            clearCamBuffer(app);
            imNewColorGrid = getFrame(app);

            % preview
            app.CamSceneImg.ImageSource = imScene;
            app.CamCapPrjImg.ImageSource = imNewColorGrid;

            % check if the two images are similar (due to camera's slow buffer or small exposure time, increase buffer count or exposure time)
%             if(immse(im2double(imScene), im2double(imNewColorGrid)) < 0.001)
%                 uialert(app.ProCamCalibUIFigure, 'Seems the camera light image is the same as color grid image, try a longer Exposure Time or larger buffer count and recapture','Error');
%                 return;
%             end

            updateCalibImageList(app);
            
            % segment color grid image 
            [camCorners, ~, ~] = detectCheckerboardPoints(imScene, PartialDetections=false);
            % [imBWGrid, imColorGridMasked] = ImgProc.segColorGrid(imScene, imNewColorGrid, camCorners,0);
            
            % color to label using look-up table
            imOut = AdaptiveGenPattern.segColorGridAndColorToLabel(imScene, imNewColorGrid, camCorners, hueLabel, 0);
            
            %display result
            fs(imOut);
            
            figure;
            subplot(1,2,1);
            imshow(imNewColorGrid);
        
            subplot(1,2,2);
            imshow(imOut);
        end
       
        function captureImg(app)
            % if first time to click "capture", browse for a new dataRoot
            if(~ischar(app.dataRoot))
                app.CreatenewdatadirectryMenuSelected() % use this function to browse a dir to save captured data
            end
            
            % if user clicked cancel in uigetdir dialog
            if(app.dataRoot) % if(~app.dataRoot) may cause errors
                dataFolder = fullfile(app.dataRoot, app.dataName);
            else
                uialert(app.ProCamCalibUIFigure, 'No data root dir selected, capture stopped.', 'Warning')
                return;
            end

            % find camera
            camName = app.CameraNameDropDown.Value;
            if(isempty(camName))
                uialert(app.ProCamCalibUIFigure, 'Camera not found','Error');
                return;
            end

            % start cam preview
            if(isempty(app.camPreviewImg))
                app.CamPreviewButton.Value = 1; % enable preview
                app.CamPreviewButtonValueChanged();
            end

            % start projection
            screenID = app.ProjectorDisplayDropDown.Value;
            screenW = app.screenSize(screenID, 4);
            screenH = app.screenSize(screenID, 3);
            
            capType = app.CaptureTypeButtonGroup.SelectedObject.Text;
            
            % Calibration
            if(strcmp(capType, 'Calib'))
                calibFolder = Utils.mkdirs(fullfile(dataFolder, 'calib'));
%                 HuangFolder = Utils.mkdirs(fullfile(dataFolder, 'Huang'));
%                 ProposedFolder = Utils.mkdirs(fullfile(dataFolder, 'Proposed'));

                % project pure black image and capture imScene
                imBlack = zeros(screenW, screenH, 3); % in case desktop provides some lighting, we cover the fullscreen with a black image
                projectImage(app, imBlack);

                % larger camExposureTime and CamBufferSpinner.Value should have larger exposureTime, if you see partial color grid (due to insufficient expoure time), increase exposureTime.
                % clear buffer
                pause(app.ExposureTimesSlider.Value);
                clearCamBuffer(app);
                imScene = getFrame(app);

                % project color grid and capture imColorGrid
%                 numHoriColor = app.numColor(1);
%                 numVertColor = app.numColor(2);

                adaptiveOption = 1;
                dataFolder = fullfile(app.dataRoot, app.dataName);
                adapFolder = fullfile(dataFolder, 'adap');
                currCompHsv = load(fullfile(adapFolder, ['currCompHsv.mat']));                
                currCompHsv = currCompHsv.currCompHsv;
                
                app.prjColorGrid = ImgProc.genStructuredLight(screenH, screenW, adaptiveOption, currCompHsv, app.numColor);
                projectImage(app, app.prjColorGrid * app.PrjBrightnessSlider.Value);

                % clear buffer
                pause(app.ExposureTimesSlider.Value);
                clearCamBuffer(app);
                imColorGrid = getFrame(app);
                
                % preview
                app.CamSceneImg.ImageSource = imScene;
                app.CamCapPrjImg.ImageSource = imColorGrid;

                % check if the two images are similar (due to camera's slow buffer or small exposure time, increase buffer count or exposure time)
%                 if(immse(im2double(imScene), im2double(imColorGrid)) < 0.001)
%                     uialert(app.ProCamCalibUIFigure, 'Seems the camera light image is the same as color grid image, try a longer Exposure Time or larger buffer count and recapture','Error');
%                     return;
%                 end

                updateCalibImageList(app);

                % if empty sets
                if(numel(app.CalibImageListBox.Items) == 0)
                    missingIdx = [1];
                else
                    % check if some sets were missing, if so fill them using the newly captured images
                    missingIdx = findMissingSetIdx(app, app.CalibImageListBox.Items);
                end

                % save captured images
                cv.imwrite(fullfile(calibFolder, ['scene', sprintf('%02d', missingIdx(1)), '.png']), imScene);
                cv.imwrite(fullfile(calibFolder, ['colorGrid', sprintf('%02d', missingIdx(1)), '.png']), imColorGrid);
                
%                 if(rem(missingIdx(1),2) ~= 0)
%                     cv.imwrite(fullfile(HuangFolder, ['scene', sprintf('%02d', (missingIdx(1)+1)/2), '.png']), imScene);
%                     cv.imwrite(fullfile(HuangFolder, ['colorGrid', sprintf('%02d', (missingIdx(1)+1)/2), '.png']), imColorGrid);
%                 else
%                     cv.imwrite(fullfile(ProposedFolder, ['scene', sprintf('%02d', (missingIdx(1))/2), '.png']), imScene);
%                     cv.imwrite(fullfile(ProposedFolder, ['colorGrid', sprintf('%02d', (missingIdx(1))/2), '.png']), imColorGrid);
%                 end

                % save calib-info
                saveCalibInfo(app, imScene);

                updateCalibImageList(app);

                % reconstruction
            elseif(strcmp(capType, 'Recon'))
                % check recon folder existence
                reconDir = Utils.mkdirs(fullfile(dataFolder, 'recon'));
                app.prjGrayPattern = Reconstruct.createGrayPattern(screenH, screenW);

                % if empty sets
                if(isempty(app.ReconImageTree.Children))
                    missingIdx = [1];
                else
                    % check if some sets were missing, if so fill them using the newly captured images
                    missingIdx = findMissingSetIdx(app, {app.ReconImageTree.Children.Text});
                end

                reconSetDir = fullfile(reconDir, sprintf('Set%02d', missingIdx(1)));
                Utils.mkdirs(reconSetDir);

                % project and capture
                projectAndCapture(app, app.prjGrayPattern * app.PrjBrightnessSlider.Value, reconSetDir);

                updateReconImageTree(app);
            end
        end
        
        % project and capture function
        function projectAndCapture(app, prjImgs, camImgSaveDir, idx)
            arguments
                app
                prjImgs (:,:,3,:) {mustBeNumeric}
                camImgSaveDir char
                idx (1,2) {mustBeNumeric}=[1, size(prjImgs, 4)]
            end

            app.IndexSpinner.Value = 1;
            
            % Camera-captured scene image
            imBlack = zeros(size(prjImgs, 1:3)); % in case desktop provides some lighting, we cover the fullscreen with a black image
            projectImage(app, imBlack);
%             pause(0.1);
            app.clearCamBuffer();
            app.CamSceneImg.ImageSource = getFrame(app); 

            for i = idx(1):idx(2)
                if(~app.capturing)
                    uialert(app.ProCamCalibUIFigure, 'Capture stopped by user.','Interruption');
                    return;
                end

                imCamName =  fullfile(camImgSaveDir, sprintf('img_%04d.png', i));

                % project
                projectImage(app, prjImgs(:,:,:,i));
                pause(0.02);
                if(i~=1)
                    app.IndexSpinner.Value = app.IndexSpinner.Value + 1;
                end

                % clear buffer
                % pause(app.ExposureTimesSlider.Value);
                app.clearCamBuffer();
                im = getFrame(app);

                % preview and save
                app.CamCapPrjImg.ImageSource = im;
                cv.imwrite(imCamName, im); % faster than MATLAB's imwrite
            end
        end
        
        % get a camera frame
        function im = getFrame(app)
%             im = app.camPreviewImg.CData; % faster for MATLAB<=2019b
            
            im = app.cam.snapshot; % faster since MATLAB>2019b
            
            % eliminate flicker
            maxImages = 10;
            im = im2double(im)/maxImages;
            
            for i = 1:maxImages-1
                singleImg = app.cam.snapshot; % faster since MATLAB>2019b
                im = im + im2double(singleImg)/maxImages;
            end
            
            im = im2uint8(im);
                 
            % for DeProCams Cannon6D with CamLink 4K. Comment this for other cases
            camName = app.cam.Name;
            if(contains(camName, 'Cam Link 4K'))
                im = imresize(im(:, 240:1679,:), app.camImgSize); % compared with cv.resize, MATLAB imresize uses lowpass filter for anti-aliasing.
            elseif(contains(camName, 'EOS Webcam Utility'))
                % [bug] MATLAB 2021a shows upside down frames of EOS Webcam Utility
                im = flipud(im);
            end

            % resize image to the given size, preview and save
            im = imresize(im, app.camImgSize);
        end
        
        % create tree nodes
        function tree = createTreeNodes(app, tree, textCell)
            for i=1:length(textCell)
                curText = textCell{i};
                curNode = uitreenode(tree, 'Text', curText);
            end
        end
        
        % avoid mis-projection due to different input pattern resolutions
        function restartProjectionWin(app)
            % close projector preview figure when change capture type
            closePrjPreviewWin(app);
            app.ProjectorPreviewButton.Value = 1;
            ProjectorPreviewButtonValueChanged(app);
        end
    end


    % private methods
    methods (Access = private)

        function initCamSliders(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            for i = 1:numel(app.camSliderProperties)
                propertyName = app.camSliderProperties{i};
                sliderName = [propertyName, 'Slider'];
                if(any(contains(propertyNames, propertyName)))

                    app.(sliderName).Enable = true;
                    %                     app.(sliderName).Limits = double(app.camInfo{camID}.(propertyName).ConstraintValue);
                    app.(sliderName).Limits = str2double(app.camInfo{camID}.(propertyName).ConstraintValue);
                    app.(sliderName).Value = app.cam.(propertyName);
                    app.(sliderName).Tag = propertyName; % for later use
                else
                    app.(sliderName).Enable = false;
                end
            end

        end

        function resetCamSliders(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            for i = 1:numel(app.camSliderProperties)
                propertyName = app.camSliderProperties{i};
                sliderName = [propertyName, 'Slider'];

                if(any(contains(propertyNames, propertyName)))

                    app.(sliderName).Value = double(app.camInfo{camID}.(propertyName).DefaultValue);
                    app.cam.(propertyName) = app.(sliderName).Value;

                end
            end

        end

        function initCamDropDowns(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names

            for i = 1:numel(app.camDropDownProperties)
                propertyName = app.camDropDownProperties{i};
                dropDownName = [propertyName, 'DropDown'];

                if(any(contains(propertyNames, propertyName)))
                    app.(dropDownName).Items = app.camInfo{camID}.(propertyName).ConstraintValue;
                    app.(dropDownName).Tag = propertyName; % for later use

                    % matlab image aquisition toolbox's BacklightCompensation is a string, while webcam's is 0 or 1
                    if(strcmp(propertyName, 'BacklightCompensation'))
                        if(app.cam.(propertyName) == 1)
                            app.(dropDownName).Value = 'on';
                        else
                            app.(dropDownName).Value = 'off';
                        end
                    else
                        app.(dropDownName).Value = app.cam.(propertyName);
                    end

                    app.(dropDownName).Enable = true;
                else
                    app.(dropDownName).Enable = false;
                end
            end

        end


        function resetCamDropDowns(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names
            for i = 1:numel(app.camDropDownProperties)
                propertyName = app.camDropDownProperties{i};
                dropDownName = [propertyName, 'DropDown'];

                if(any(contains(propertyNames, propertyName)))
                    app.(dropDownName).Value = app.camInfo{camID}.(propertyName).DefaultValue;

                    try
                        if(strcmp(propertyName, 'BacklightCompensation'))
                            app.cam.(propertyName) =  strcmp(app.(dropDownName).Value, 'on');
                        else
                            app.cam.(propertyName) = app.(dropDownName).Value;
                        end
                    catch e
                        disp(e) % for debug
                    end
                end
            end
        end


        % preview the input image in a axis specified by its name
        %         function previewImage(app, axisName, im)
        %             app.(axisName).PlotBoxAspectRatio = [size(im, 2), size(im, 1), size(im, 1)];
        %             app.(axisName).XLim = [0 size(im, 2)];
        %             app.(axisName).YLim = [0 size(im, 1)];
        %             image(app.(axisName), im);
        %         end

        % use try catch to get camera property constraint values
        function getCamPropertyConstraint(app, camID)
            propertyNames = fieldnames(app.cam); % webcam property names

            % sliders
            for i = 1:numel(app.camSliderProperties)
                propertyName = app.camSliderProperties{i};

                if(any(contains(propertyNames, propertyName)))
                    try
                        app.cam.(propertyName) = inf;
                    catch e
                        app.camInfo{camID}.(propertyName).ConstraintValue = regexp(e.message,'-?\d*','match');
                    end

                    app.camInfo{camID}.(propertyName).DefaultValue = app.cam.(propertyName);
                end
            end

            % dropdowns
            if(any(contains(propertyNames, 'BacklightCompensation')))
                app.camInfo{camID}.BacklightCompensation.ConstraintValue = {'on', 'off'};
                app.camInfo{camID}.BacklightCompensation.DefaultValue = 'off';
            end

            if(any(contains(propertyNames, 'ExposureMode')))
                app.camInfo{camID}.ExposureMode.ConstraintValue = {'auto', 'manual'};
                app.camInfo{camID}.ExposureMode.DefaultValue = 'manual';
            end

            if(any(contains(propertyNames, 'WhiteBalanceMode')))
                app.camInfo{camID}.WhiteBalanceMode.ConstraintValue = {'auto', 'manual'};
                app.camInfo{camID}.WhiteBalanceMode.DefaultValue = 'manual';
            end

            if(any(contains(propertyNames, 'FocusMode')))
                app.camInfo{camID}.FocusMode.ConstraintValue = {'auto', 'manual'};
                app.camInfo{camID}.FocusMode.DefaultValue = 'manual';
            end
            % set ExposureMode to manual and BacklightCompensation to off, since our calibration algorithm requires the scene and colorGrid images have the same background brightness
        end

        % save calibration setup info to a yml file
        function saveCalibInfo(app, imScene)
            if(isempty(app.calibInfo) && ~isempty(imScene))
                % resize image to width = 640, since MATLAB detectCheckerboardPoints may fail on large images [bug?].
                if(size(imScene,2) > 640)
                    imSceneSmall = imresize(imScene, [nan, 640]);
                else
                    imSceneSmall = imScene;
                end

                % find checkerboard #corners
                [~, boardSize, ~] = detectCheckerboardPoints(imSceneSmall, PartialDetections=false);

                if(any(boardSize == 0))
                    return;
                end

                % cam and prj resolutions
                app.calibInfo.camW = size(imScene, 2);
                app.calibInfo.camH = size(imScene, 1);

                app.calibInfo.prjW = size(app.prjColorGrid, 2);
                app.calibInfo.prjH = size(app.prjColorGrid, 1);

                % checkerboard #corners and square size
                app.calibInfo.boardSize = boardSize;
                app.calibInfo.sqSize = app.CheckerboardSquareSizeEditField.Value;
                
                % save the number of colors
                app.calibInfo.numColor = app.numColor;                 
            end
                

            % update sets
            updateCalibImageList(app);
            app.calibInfo.sets = cellfun(@(x) str2num(x(end-1:end)), app.CalibImageListBox.Items);
            
            calibFolder = fullfile(app.dataRoot, app.dataName, 'calib');
            %             cv.FileStorage('calib-info.yml', app.calibInfo);
            writeToYML(app, app.calibInfo, fullfile(calibFolder, 'calib-info.yml'));
        end

        % function to write to yml data structure
        function writeToYML(app, obj, fileName)
            fieldNames = fieldnames(obj);
            f = fopen(fileName, 'w');
            fprintf(f, '%s\n', '%YAML:1.0');
            fprintf(f, '%s\n', '# This is the calibration config file for later use');

            for i = 1: numel(fieldNames)
                fieldName = fieldNames{i};
                data = obj.(fieldName);
                len = length(data);
                if(len > 1)
                    str = [];
                    for j=1:len
                        if(j==1)
                            str = num2str(data(j));
                        else
                            str = [str, ', ' , num2str(data(j))];
                        end

                    end
                    fprintf(f, [fieldName, ': [%s]\n'], str);
                else
                    fprintf(f, [fieldName, ': ', num2str(data),'\n']);
                end
            end
            fclose(f);
        end

        function startCamPreview(app)
            app.CamPreviewButton.Value = 1;

            app.camPreviewImg = app.cam.preview;
            f = ancestor(app.camPreviewImg, 'figure');
            f.DeleteFcn = createCallbackFcn(app, @disablePreviewCamStateBtn, true); % set preview state button to disable when window is closed
        end

        function setComponentKeyVal(app, fieldName, key, val)
            app.(fieldName).(key) = val;
        end

        function disablePreviewCamStateBtn(app, callbackData)
            if(isvalid(app))
                app.CamPreviewButton.Value = 0;
                %                 app.CameraAdjusmentPanel.Visible = false;
            end
        end

        function disablePreviewPrjStateBtn(app, callbackData)
            if(isvalid(app))
                app.ProjectorPreviewButton.Value = 0;
                app.PrjBrightnessSlider.Enable = false;
            end
        end

        % update projector preview images (both the projector screen and the preview image)
        function updatePrjPreviewImg(app)
            screenID = app.ProjectorDisplayDropDown.Value;
            screenW = app.screenSize(screenID, 4);
            screenH = app.screenSize(screenID, 3);
            
%             opt = app.InputPatterrnDropDown.Value;
            opt = app.InputPatternTree.SelectedNodes.Text;
            
            if(isempty(opt))
                return;
            end

            if(strcmp(opt, 'grid'))
                % project color grid
                app.numColor = [app.horicolorsEditField.Value, app.vertcolorsEditField.Value];
                
                % set adaptive option
                adaptiveOption = 1;
                
                % preview image option
%                 preImgOpt = 0;
                if(app.dataName)
                    % load adaptive data
                    adapFolder = fullfile(app.dataRoot, app.dataName, 'adap');
                    
                    if(~exist(adapFolder, 'dir'))
                        preImgOpt = 0;
                    else
                        preImgOpt = 1;
                    end
                else
                    preImgOpt = 0;
                end
                
                % generate preview image
                if(preImgOpt)
                    currCompHsv = load(fullfile(adapFolder, ['currCompHsv.mat']));                
                    currCompHsv = currCompHsv.currCompHsv;
                else
                    horiHue = linspace(0, 1, app.numColor(1)+1);
                    vertHue = linspace(0, 1, app.numColor(2)+1);
                    
                    outputHue = zeros([2*max(app.numColor(1), app.numColor(2)), 1]);
                
                    for i = 1:app.numColor(1)
                        outputHue(2*i-1) = horiHue(i);
                    end
                    
                    for i = 1:app.numColor(2)
                        outputHue(2*i) = vertHue(i);
                    end
                
                    % set saturation and value to 1
                    currCompHsv = outputHue;
                    currCompHsv(:,2:3) = deal(1);
                end
                       
                app.prjColorGrid = ImgProc.genStructuredLight(screenH, screenW, adaptiveOption, currCompHsv, app.numColor);
                
                if(app.ProjectorPreviewButton.Value)
                    projectImage(app, app.prjColorGrid*app.PrjBrightnessSlider.Value);
                else
                    app.PrjInputImg.ImageSource = app.prjColorGrid*app.PrjBrightnessSlider.Value;
                end
            elseif(sum(strcmp(opt, {'Gray code', 'sl'})) > 0)
                if(isempty(app.prjGrayPattern))
                    app.prjGrayPattern = Reconstruct.createGrayPattern(screenH, screenW);
                end
                
                app.IndexSpinner.Limits = [1, size(app.prjGrayPattern, 4)];
                idx = app.IndexSpinner.Value;
                im = app.prjGrayPattern(:,:,:,idx)  * app.PrjBrightnessSlider.Value;
                if(app.ProjectorPreviewButton.Value)
                    projectImage(app, im);
                else
                    app.PrjInputImg.ImageSource = im;
                end
            elseif(sum(strcmp(opt, app.cmpCapTypes)) > 0)
                if(app.dataRoot)
                    curDataFolder = fullfile(app.dataRoot, '../', opt);
                    if(~exist(curDataFolder, 'dir'))
                        uialert(app.ProCamCalibUIFigure, ['Cannot find ', curDataFolder], 'Error');
                        return;
                    end
                    
                    imgList = Utils.listDir(curDataFolder, '*.png');
                    app.IndexSpinner.Limits = [1, length(imgList)];

                    idx = app.IndexSpinner.Value;
                    im = imread(fullfile(curDataFolder, imgList(idx).name)) * app.PrjBrightnessSlider.Value;
                    if(app.ProjectorPreviewButton.Value)
                        projectImage(app, im);
                    else
                        app.PrjInputImg.ImageSource = im;
                    end
                end
            end
        end

        % update calibration image list
        function updateCalibImageList(app)
            if(app.dataRoot)
                calibFolder = fullfile(app.dataRoot, app.dataName, 'calib');
                setList = dir(fullfile(calibFolder, '*scene*.png'));
                app.CalibImageListBox.Items = {};

                for i=1:numel(setList)
                    fileName = setList(i).name;
                    app.CalibImageListBox.Items{i} = ['Set', fileName(end-5:end-4)];
                end
            end
        end

        % update reconstruction image tree
        function updateReconImageTree(app)
            reconFolder = fullfile(app.dataRoot, app.dataName, 'recon');
            
            if(exist(reconFolder, 'dir'))
                reconList = Utils.listDir(reconFolder, 'dir');
                app.ReconImageTree.Children.delete; % clear all previous recon sets

                for i = 1:numel(reconList)
                    imgList = dir(fullfile(reconFolder, reconList(i).name, '*.png'));
                    curReconNode = uitreenode(app.ReconImageTree, 'Text', reconList(i).name, 'Icon', fullfile(reconFolder, reconList(i).name, imgList(1).name));

                    for j = 1:numel(imgList)
                        [~, imgName, ~] = fileparts(imgList(j).name);
                        uitreenode(curReconNode, 'Text', imgName);
                    end
                end
            else
                app.ReconImageTree.Children.delete; % clear all previous recon sets
            end
        end

        % find missing set index for calibration and reconstruction image list
        function missingIdx = findMissingSetIdx(app, inputCell)
            existingIdx = cellfun(@(x) str2num(x(end-1:end)), inputCell);
            missingIdx = setdiff(1:str2num(inputCell{end}(end-1:end)), existingIdx);
            if(isempty(missingIdx))
                missingIdx = [existingIdx(end) + 1];
            end
        end
        
        % close projector preview window/figure
        function closePrjPreviewWin(app)
            if(~isempty(app.prjFigHandel) && isvalid(app.prjFigHandel))
                % delete color grid figure
                delete(app.prjFigHandel);
                app.prjFigHandel = [];
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.logDir = Utils.mkdirs('log'); % create log folder
            initCameras(app);
            getScreenInfo(app);
            CaptureTypeButtonGroupSelectionChanged(app);
        end

        % Value changed function: CameraNameDropDown
        function CameraNameDropDownValueChanged(app, event)
            camName = app.CameraNameDropDown.Value;
            camID = find(contains(app.CameraNameDropDown.Items, camName));

            if(app.CamPreviewButton.Value)
                app.cam = webcam(camID);

                % also get this cam's property contraint
                if(length(app.camInfo)<camID || isempty(app.camInfo{camID}))
                    getCamPropertyConstraint(app, camID);
                end

                app.startCamPreview;
                updateCamControlPannel(app, camID);
                updateCamResolutionDropDown(app);
            end

        end

        % Close request function: ProCamCalibUIFigure
        function ProCamCalibUIFigureCloseRequest(app, event)
            closePrjPreviewWin(app);
            delete(app);
        end

        % Value changed function: ResolutionDropDown
        function ResolutionDropDownValueChanged(app, event)
            if(app.CamPreviewButton.Value)
                app.cam.Resolution = app.ResolutionDropDown.Value;
                app.startCamPreview;
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            camID = find(contains(app.CameraNameDropDown.Items, app.CameraNameDropDown.Value));
            if(~isempty(camID))
                resetCamSliders(app, camID);
                resetCamDropDowns(app, camID);
            end
        end

        % Value changed function: ProjectorDisplayDropDown
        function ProjectorDisplayDropDownValueChanged(app, event)
            if(app.ProjectorPreviewButton.Value)
                app.ProjectorPreviewButton.Value = 0; % this does not invoke app.ProjectorPreviewButtonValueChanged, why
                app.ProjectorPreviewButtonValueChanged(app);
                app.ProjectorPreviewButton.Value = 1;
                app.ProjectorPreviewButtonValueChanged(app);
            end
            app.prjGrayPattern = []; % clear Gray code patterns
            updatePrjPreviewImg(app);
        end

        % Value changed function: BrightnessSlider, ContrastSlider, 
        % ExposureSlider, GainSlider, GammaSlider, HueSlider, 
        % SaturationSlider, SharpnessSlider, WhiteBalanceSlider
        function CamSliderValueChanged(app, event)
            propertyName = event.Source.Tag; % this is set when this component is initialized, mannually
            if(~isempty(app.cam))
                try
                    app.cam.(propertyName) = app.([propertyName, 'Slider']).Value;
                catch e
                    disp(e) % for debug
                end
            end
        end

        % Callback function: ChangedatadirectryMenu, DatafolderButton
        function BrowseDataFolderButtonPushed(app, event)
            if(~ischar(app.dataRoot))
                app.dataRoot = pwd;
            end
            dataFolder = uigetdir(app.dataRoot, 'Where is the captured data folder?');
            figure(app.ProCamCalibUIFigure);
            
            if(dataFolder)
                fileSepIdx = strfind(dataFolder, filesep); % output of filesep depends on platform
                app.dataRoot = dataFolder(1:fileSepIdx(end)-1);
                app.dataName = dataFolder(fileSepIdx(end)+1:end);
                app.DataDirectoryLabel.Text = dataFolder;
                calibFolder = fullfile(dataFolder, 'calib');

                updateCalibImageList(app);
                updateReconImageTree(app);

                if(~isempty(app.CalibImageListBox.Items))
                    % update image preview
                    app.CalibImageListBox.Value = app.CalibImageListBox.Items{1};
                    CalibImageListBoxValueChanged(app, event);

                    % load calib info
                    calibInfoFile = fullfile(calibFolder, 'calib-info.yml');
                    if(exist(calibInfoFile, 'file'))
                        app.calibInfo = cv.FileStorage(fullfile(calibFolder, 'calib-info.yml'));
                        % convert cell to mat
                        if(isfield(app.calibInfo, 'boardSize') && isa(app.calibInfo.boardSize, 'cell'))
                            app.calibInfo.boardSize = cell2mat(app.calibInfo.boardSize);
                        end
                        if(isa(app.calibInfo.sets, 'cell'))
                            app.calibInfo.sets = cell2mat(app.calibInfo.sets);
                        end
                    end

                    % load calibration results
                    calibFileFullName = fullfile(calibFolder, 'results', 'calibration.yml');
                    if(exist(calibFileFullName, 'file'))
                        app.stereoParams = cv.FileStorage(calibFileFullName);
                        app.updateCalibrationResultText(calibFileFullName, app.stereoParams);
                    end
                else
                    if(~isempty(app.PreviewImage.ImageSource))
                        app.PreviewImage.ImageSource = zeros(1, 1, 3, 'uint8'); % an empty black image
                    end
%                     uialert(app.ProCamCalibUIFigure, 'No lightGird image found in current folder!', 'No image found');
                end
            else
                app.dataRoot = 0;
            end
        end

        % Value changed function: CalibImageListBox
        function CalibImageListBoxValueChanged(app, event)
            app.CalibImageTypeButtonGroup.Enable = 'on';
            app.previewCalibImageType = app.CalibImageTypeButtonGroup.SelectedObject.Text;
            imgName = app.CalibImageListBox.Value;
            im = imread(fullfile(app.dataRoot, app.dataName, 'calib', [app.previewCalibImageType, imgName{1}(end-1:end), '.png']));
            %             previewImage(app, 'CalibUIAxes', im);
            app.PreviewImage.ImageSource = im;
        end

        % Selection changed function: CalibImageTypeButtonGroup
        function CalibImageTypeButtonGroupSelectionChanged(app, event)
            if(~isempty(app.CalibImageListBox.Value))
                app.previewCalibImageType = app.CalibImageTypeButtonGroup.SelectedObject.Text;
                CalibImageListBoxValueChanged(app, event);
            end
        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            app.CalibImageListBox.Value = app.CalibImageListBox.Items;
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed(app, event)
            selectedSets = app.CalibImageListBox.Value;
            selectedIdx = cell2mat(cellfun(@(x) str2num(x(end-1:end)), selectedSets, 'UniformOutput', false));

            % check if at least 3 sets are selected
            if(numel(selectedSets) >= 3)
                app.calibOption.sets = selectedIdx;
                app.calibOption.sqSize = app.CheckerboardSquareSizeEditField.Value;
                app.calibOption.verbose = app.VerbosedebugCheckBox.Value;
                app.calibOption.useParallel = app.UseParallelCheckBox.Value;
                app.calibOption.useExistingMask = app.UseExistingMaskCheckBox.Value;
                app.calibOption.useExistingCamCorners = app.UseExistingCamCornersCheckBox.Value;
                app.calibOption.useDistortion = app.EstimateLensDistortionCheckBox.Value;

                calibrateGUI(app); % call calibration function
            else
                uialert(app.ProCamCalibUIFigure, 'Need at lease 3 sets to calibrate!', 'Not enough sets');
            end

        end

        % Value changed function: CamPreviewButton
        function CamPreviewButtonValueChanged(app, event)
            camPreviewEnabled = app.CamPreviewButton.Value;

            if(camPreviewEnabled)
                camName = app.CameraNameDropDown.Value;

                if(isempty(camName))
                    uialert(app.ProCamCalibUIFigure, 'Camera not found','Error');
                    return;
                end

                % init cam if it was not initialized
                if(isempty(app.cam) || ~strcmp(camName, app.cam.Name))
                    camID = find(contains(app.CameraNameDropDown.Items, camName));
                    app.cam = webcam(camID);

                    % also get this cam's property contraint
                    if(length(app.camInfo)<camID)
                        getCamPropertyConstraint(app, camID);
                    end

                    % update camera control panel and resolution list
                    updateCamControlPannel(app, camID);
                    updateCamResolutionDropDown(app);
                end

                app.startCamPreview;
                %                 app.CameraAdjusmentPanel.Visible = true;
            else
                %                 app.CameraAdjusmentPanel.Visible = false;

                if(~isempty(app.cam))
                    app.cam.closePreview;
                end
            end
        end

        % Value changed function: CheckerboardSquareSizeEditField
        function CheckerboardSquareSizeEditFieldValueChanged(app, event)
            app.calibOption.sqSize = app.CheckerboardSquareSizeEditField.Value;
        end

        % Value changed function: VerbosedebugCheckBox
        function VerbosedebugCheckBoxValueChanged(app, event)
            app.calibOption.verbose = app.VerbosedebugCheckBox.Value;
        end

        % Value changed function: UseExistingCamCornersCheckBox
        function UseExistingCamCornersCheckBoxValueChanged(app, event)
            app.calibOption.useExistingCamCorners = app.UseExistingCamCornersCheckBox.Value;
        end

        % Value changed function: ProjectorPreviewButton
        function ProjectorPreviewButtonValueChanged(app, event)
            if(app.ProjectorPreviewButton.Value)
                app.PrjBrightnessSlider.Enable = true;
                updatePrjPreviewImg(app);
            else
                app.PrjBrightnessSlider.Enable = false;
                closePrjPreviewWin(app);
            end
        end

        % Value changed function: PrjBrightnessSlider
        function PrjBrightnessSliderValueChanged(app, event)
            updatePrjPreviewImg(app);
        end

        % Button pushed function: AutoSegButton
        function AutoSegButtonPushed(app, event)
            if(isempty(event))
                % invoked by reconstructGUI
                return;
            end

            imgName = app.CalibImageListBox.Value;

            if(isempty(imgName))
                uialert(app.ProCamCalibUIFigure, 'No image selected','Error');
                return;
            end

            scene = fullfile(app.dataRoot, app.dataName, 'calib', ['scene', imgName{1}(end-1:end), '.png']);
            colorGrid = fullfile(app.dataRoot, app.dataName, 'calib', ['colorGrid', imgName{1}(end-1:end), '.png']);

            [camCorners, ~, ~] = detectCheckerboardPoints(scene, PartialDetections=false);

            if(isempty(camCorners))
                uialert(app.ProCamCalibUIFigure, 'No checkerboard corners detected, cannot segment. Please skip or delete this set.','Error');
                return;
            elseif(sum(isnan(camCorners(:))) || prod(app.calibInfo.boardSize-1) ~= length(camCorners))
                uialert(app.ProCamCalibUIFigure, 'Wrong checkerboard corners detected, please skip or delete this set.','Error');
%                 return;
            end

            [~, imColorGridMasked, imBWBoardMask] = ImgProc.segColorGrid(scene, colorGrid, camCorners, 0, false);

            if(strcmp(app.previewCalibImageType, 'scene'))
                %                 previewImage(app, 'CalibUIAxes', cat(3,imBWBoardMask,imBWBoardMask,imBWBoardMask));
                app.PreviewImage.ImageSource = im2uint8(cat(3,imBWBoardMask,imBWBoardMask,imBWBoardMask));
            else
                %                 previewImage(app, 'CalibUIAxes', imColorGridMasked);
                app.PreviewImage.ImageSource = imColorGridMasked;
            end
            
            % draw detected checkerboard corners
            for i = 1:length(camCorners)
                app.PreviewImage.ImageSource = insertMarker(app.PreviewImage.ImageSource, camCorners(i,:), 'o', 'Color', 'green', 'Size', 2);
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;

            if(strcmp(selectedTab.Title, 'Calibration'))
                updateCalibImageList(app);
            end
        end

        % Button pushed function: ManualSegButton
        function ManualSegButtonPushed(app, event)
            imgName = app.CalibImageListBox.Value;

            if(isempty(imgName))
                uialert(app.ProCamCalibUIFigure, 'No image selected','Error');
                return;
            end

            scene = fullfile(app.dataRoot, app.dataName, 'calib', ['scene', imgName{1}(end-1:end), '.png']);

            f = figure;
            imshow(imread(scene));title('Draw ROI by clicking and dragging left mouse button')
            h = drawfreehand(f.Children);

            if(isvalid(h))
                imMask = createMask(h);
                if(isempty(event))
                    % invoked by reconstructGUI
                    cv.imwrite(fullfile(app.dataRoot, app.dataName, 'calib', ['objectROI', imgName{1}(end-1:end), '.png']), im2uint8(imMask));
                else
                    cv.imwrite(fullfile(app.dataRoot, app.dataName, 'calib', ['bwBoard', imgName{1}(end-1:end), '.png']), im2uint8(imMask));
                end

                delete(f);

                % invoke auto seg
                AutoSegButtonPushed(app, event);
            end
        end

        % Button pushed function: LoadCalibrationButton
        function LoadCalibrationButtonPushed(app, event)
            [calibFileName, calibFilePath] = uigetfile(fullfile(app.dataRoot, '.yml'), 'Select existing calibration result file');
            figure(app.ProCamCalibUIFigure);
            if(calibFileName)
                calibFileFullName = fullfile(calibFilePath, calibFileName);
                app.stereoParams = cv.FileStorage(calibFileFullName);
                app.updateCalibrationResultText(calibFileFullName, app.stereoParams);
            end
        end

        % Value changed function: ExposureTimesSlider
        function ExposureTimesSliderValueChanged(app, event)
            %             app.camExposureTime = app.ExposureTimesSlider.Value;
        end

        % Button pushed function: ReconstructSigleshotButton
        function ReconstructSigleshotButtonPushed(app, event)
            if(isempty(app.stereoParams))
                uialert(app.ProCamCalibUIFigure, 'No calibration parameters, click Load Calibration and load results/calibration.yml','Error');
                return;
            else
                selectedSets = app.CalibImageListBox.Value;

                % check if only 1 set is selected
                if(numel(selectedSets) > 1)
                    uialert(app.ProCamCalibUIFigure, 'Only one set is allowed for reconstruction each time','Error');
                    return;
                else
                    selectedIdx = cell2mat(cellfun(@(x) str2num(x(end-1:end)), selectedSets, 'UniformOutput', false));
                    app.reconOption.sets = selectedIdx;
                    app.reconOption.verbose = app.ReconVerbosedebugCheckBox.Value;
                    app.reconOption.epiThresh = app.EpipolarThreshpixelEditField.Value;
                    app.reconOption.useExistingMask = app.UseExistingMaskCheckBox.Value;
                    app.reconOption.useEdge = app.UseEdgePixelsCheckBox.Value;
                    
                    reconstructSingleShotGUI(app);
                end
            end
        end

        % Value changed function: EpipolarThreshpixelEditField
        function EpipolarThreshpixelEditFieldValueChanged(app, event)
            app.reconOption.epiThresh = app.EpipolarThreshpixelEditField.Value;
        end

        % Value changed function: UseParallelCheckBox
        function UseParallelCheckBoxValueChanged(app, event)
            app.calibOption.useParallel = app.UseParallelCheckBox.Value;
        end

        % Value changed function: UseExistingMaskCheckBox
        function UseExistingMaskCheckBoxValueChanged(app, event)
            app.calibOption.useExistingMask = app.UseExistingMaskCheckBox.Value;
        end

        % Value changed function: EstimateLensDistortionCheckBox
        function EstimateLensDistortionCheckBoxValueChanged(app, event)
            app.calibOption.useDistortion = app.EstimateLensDistortionCheckBox.Value;
        end

        % Value changed function: ReconVerbosedebugCheckBox
        function ReconVerbosedebugCheckBoxValueChanged(app, event)
            app.reconOption.verbose = app.ReconVerbosedebugCheckBox.Value;
        end

        % Value changed function: UseEdgePixelsCheckBox
        function UseEdgePixelsCheckBoxValueChanged(app, event)
            app.reconOption.useEdge = app.UseEdgePixelsCheckBox.Value;
        end

        % Callback function
        function SetupImageClicked(app, event)
            system('start https://github.com/BingyaoHuang/single-shot-pro-cam-calib');
        end

        % Callback function: DeleteButton, DeleteMenu
        function DeleteMenuSelected(app, event)
            calibFolder = fullfile(app.dataRoot, app.dataName, 'calib');

            selectedSets = app.CalibImageListBox.Value;
            selectedIdx = cellfun(@(x) x(end-1:end), selectedSets, 'UniformOutput', false);

            for i=1:numel(selectedIdx)
                for fileType = {'scene', 'colorGrid', 'bwBoard', 'objectROI'}
                    imgName = fullfile(calibFolder, [fileType{1}, selectedIdx{i}, '.png']);
                    if(exist(imgName, 'file'))
                        delete(imgName);
                    end

                    % also delete point cloud ply (if using single-shot color grid SL reconstruction) 
                    pcName = fullfile(calibFolder, ['pointCloud', selectedIdx{i}, '.ply']);
                    if(exist(pcName, 'file'))
                        delete(pcName);
                    end
                end
            end
            updateCalibImageList(app);

            % only update calib-info sets
            saveCalibInfo(app, []);
        end

        % Selection changed function: ReconImageTree
        function ReconImageTreeSelectionChanged(app, event)
            reconFolder = fullfile(app.dataRoot, app.dataName, 'recon');

            %             selectedNodes = app.ReconImageTree.SelectedNodes;
            curReconImgNode = event.SelectedNodes;

            % if parent node (Set##)
            if(contains(curReconImgNode.Text, 'Set'))
                reconSetDir = fullfile(reconFolder, curReconImgNode.Text);
                %                 app.PreviewImage.ImageSource = fullfile(reconSetDir, [curReconImgNode.Children(1).Text, '.png']);
                app.PreviewImage.ImageSource = 0.5*imread(fullfile(reconSetDir, [curReconImgNode.Children(1).Text, '.png'])) + 0.5*imread(fullfile(reconSetDir, [curReconImgNode.Children(2).Text, '.png']));
                %                 app.PreviewImage.ImageSource = mean(readAllImgs(fullfile(reconSetDir), '.png', [1,2]), 4);
            else
                % if child node (img_####)
                reconSetDir = fullfile(reconFolder, curReconImgNode.Parent.Text);
                app.PreviewImage.ImageSource = fullfile(reconSetDir, [curReconImgNode.Text, '.png']);
            end

            % disable calib image options
            app.CalibImageTypeButtonGroup.Enable = 'off';


            %             im = imread(fullfile(reconSetDir, [selectedNodes.Text, '.png']));
            %             app.ReconImage.ImageSource = fullfile(reconSetDir, [curReconImgNode.Text, '.png']);
        end

        % Button pushed function: ReconstructButton
        function ReconstructButtonPushed(app, event)
            if(isempty(app.stereoParams))
                uialert(app.ProCamCalibUIFigure, 'No calibration parameters, click Load Calibration and load results/calibration.yml','Error');
                return;
            else
                app.reconOption.verbose = app.ReconVerbosedebugCheckBox.Value;
                app.reconOption.epiThresh = app.EpipolarThreshpixelEditField.Value;
                app.reconOption.useExistingMask = app.UseExistingMaskCheckBox.Value;
                
                app.reconOption.thresh.t = app.MinmaxContrastThreshtEditField.Value;
                app.reconOption.thresh.b = app.ProjectorbacklightstengthbEditField.Value;
                app.reconOption.thresh.m = app.DirectLightThreshmEditField.Value;

                reconstructGUI(app);
            end
        end

        % Button down function: CalibrationReconstructionTab
        function CalibrationReconstructionTabButtonDown(app, event)
            updateCalibImageList(app);
            updateReconImageTree(app);
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            app.camImgSize = [app.HeightEditField.Value, app.WidthEditField.Value]; % camera captured image size
        end

        % Value changed function: HeightEditField
        function HeightEditFieldValueChanged(app, event)
            app.camImgSize = [app.HeightEditField.Value, app.WidthEditField.Value]; % camera captured image size
        end

        % Menu selected function: CreatenewdatadirectoryMenu
        function CreatenewdatadirectryCreatenewdatadirectoryMenuSelected(app, event)
            % if first time to click "capture", browse for a new dataRoot
            dataRoot = uigetdir(pwd, 'Where to save the new captured data?');
            figure(app.ProCamCalibUIFigure);
            
            if(dataRoot)
                app.dataRoot = dataRoot;
%                 app.dataName = ['calibration-', datestr(now, 'mm-dd-yy_HH-MM-SS')];
                app.dataName = datestr(now, 'yy-mm-dd-_HH-MM-SS');
                dataFolder = fullfile(app.dataRoot, app.dataName);

                % create data folder if it does not exist
                if(~exist(dataFolder, 'dir'))
                    Utils.mkdirs(dataFolder);
                    app.DataDirectoryLabel.Text = dataFolder;
                end
                % empty calibinfo
                app.calibInfo = [];
                
                app.PreviewImage.ImageSource = zeros(1, 1, 3, 'uint8'); % an empty black image
                app.stereoParams = []; % clear calibration data
                app.CalibrationResultsTextArea.Value = ''; % clear calibration results
                updateCalibImageList(app);
                updateReconImageTree(app);
            end        
        end

        % Callback function
        function PreviewPatternButtonGroupSelectionChanged(app, event)
            if(app.ProjectorPreviewButton.Value)
                updatePrjPreviewImg(app);
            end
        end

        % Button pushed function: DeleteReconButton
        function DeleteReconButtonPushed(app, event)
            reconFolder = fullfile(app.dataRoot, app.dataName, 'recon');

            curReconImgNode = app.ReconImageTree.SelectedNodes;

            % if parent node (Set##)
            if(contains(curReconImgNode.Text, 'Set'))
                % delete dir
                reconSetDir = fullfile(reconFolder, curReconImgNode.Text);
                Utils.rmdirs(reconSetDir);

                % also remove ply
                pcFileName = fullfile(reconFolder, ['pointCloud_', app.ReconImageTree.SelectedNodes.Text, '.ply']);
                if(exist(pcFileName, 'file'))
                    delete(pcFileName);
                end
            else
                % if child node (img_####)
                %                 reconSetDir = fullfile(dataFolder, 'recon', curReconImgNode.Parent.Text);
                uialert(app.ProCamCalibUIFigure, 'Can only delete a Set, not a single image!', '');
            end

            updateReconImageTree(app);
        end

        % Button pushed function: PlotErrorsButton
        function PlotErrorsButtonPushed(app, event)
            if(~isempty(app.stereoParams))
                Calibration.showReprojectionErrors(app.stereoParams, app.stereoParams);
            end
        end

        % Button pushed function: PlotExtrinsicsButton
        function PlotExtrinsicsButtonPushed(app, event)
            if(~isempty(app.stereoParams))
                Reconstruct.visualizePts3d(cell2mat(app.stereoParams.worldPts'), app.stereoParams.R, app.stereoParams.T, 'ProCams extrinsics and reconstructed SL nodes');
            end
        end

        % Value changed function: MinmaxContrastThreshtEditField
        function MinmaxContrastThreshtEditFieldValueChanged(app, event)
            app.reconOption.thresh.t = app.MinmaxContrastThreshtEditField.Value;
        end

        % Value changed function: 
        % ProjectorbacklightstengthbEditField
        function ProjectorbacklightstengthbEditFieldValueChanged(app, event)
            app.reconOption.thresh.b = app.ProjectorbacklightstengthbEditField.Value;
        end

        % Value changed function: DirectLightThreshmEditField
        function DirectLightThreshmEditFieldValueChanged(app, event)
            app.reconOption.thresh.m = app.DirectLightThreshmEditField.Value;
        end

        % Selection changed function: CaptureTypeButtonGroup
        function CaptureTypeButtonGroupSelectionChanged(app, event)
            if(app.CalibButton.Value)
                app.InputPatternTree.Children.delete;
                createTreeNodes(app, app.InputPatternTree, {'grid', 'white'});
                app.InputPatternTree.SelectedNodes = app.InputPatternTree.Children(1);

                if(strcmp(app.InputPatternTree.SelectedNodes, 'white'))
                    app.CropCheckBox.Enable = 'on';
                end
                app.IndexSpinner.Enable = 'off';
%                 app.WarpDesiredButton.Enable = 'off';
            elseif(app.ReconButton.Value)
                app.InputPatternTree.Children.delete;
                createTreeNodes(app, app.InputPatternTree, {'Gray code'});
                app.InputPatternTree.SelectedNodes = app.InputPatternTree.Children(1);
                
%                 app.CropCheckBox.Enable = 'off';
                app.IndexSpinner.Enable = 'on';
%                 app.WarpDesiredButton.Enable = 'off';
            end

            restartProjectionWin(app);
        end

        % Callback function
        function CropCheckBoxValueChanged(app, event)
            updatePrjPreviewImg(app)
        end

        % Value changed function: BacklightCompensationDropDown, 
        % ExposureModeDropDown, FocusModeDropDown, 
        % WhiteBalanceModeDropDown
        function CamControlDropDownValueChanged(app, event)
           propertyName = event.Source.Tag; % this is set when this component is initialized, mannually
            try
                if(strcmp(propertyName, 'BacklightCompensation'))
                    app.cam.BacklightCompensation = strcmp(app.BacklightCompensationDropDown.Value, 'on');
                else
                    app.cam.(propertyName) = app.([propertyName, 'DropDown']).Value;
                end
            catch e
                disp(e) % for debug
            end
        end

        % Value changed function: CaptureButton
        function CaptureButtonValueChanged(app, event)
            app.capturing = app.CaptureButton.Value;
            
            if(app.capturing)
                try
                    captureImg(app);
                catch e
                    disp(e);
                    app.CaptureButton.Value = 0;
                    return;
                end
            end
            
            app.CaptureButton.Value = 0;
            app.capturing = app.CaptureButton.Value;
        end

        % Value changed function: IndexSpinner
        function IndexSpinnerValueChanged(app, event)
                updatePrjPreviewImg(app);
        end

        % Selection changed function: InputPatternTree
        function InputPatternTreeSelectionChanged(app, event)
            if(app.ProjectorPreviewButton.Value)
                restartProjectionWin(app);
            end
            updatePrjPreviewImg(app);
            
            if(strcmp(app.InputPatternTree.SelectedNodes.Text, 'white'))
                app.CropCheckBox.Enable = 'on';
            else
                app.CropCheckBox.Enable = 'off';
            end
        end

        % Context menu opening function: ContextMenu2
        function ContextMenu2Opening(app, event)
            winopen(app.DataDirectoryLabel.Text);
        end

        % Button pushed function: AdaptButton
        function AdaptButtonPushed(app, event)
            genAdaptivePattern(app);
%             app.adaptive = app.AdaptButton.Value;
%             
%             if(app.adaptive)
%                 try
%                     genAdaptivePattern(app);
%                 catch e
%                     disp(e);
%                     app.AdaptButton.Value = 0;
%                     return;
%                 end
%             end
%             
%             app.AdaptButton.Value = 0;
%             app.adaptive = app.AdaptButton.Value;
        end

        % Value changed function: horicolorsEditField
        function horicolorsEditFieldValueChanged(app, event)
            app.numColor = [app.horicolorsEditField.Value, app.vertcolorsEditField.Value];
        end

        % Value changed function: vertcolorsEditField
        function vertcolorsEditFieldValueChanged(app, event)
            app.numColor = [app.horicolorsEditField.Value, app.vertcolorsEditField.Value];    
        end

        % Callback function
        function makeMasksButtonPushed(app, event)
            makeMasksToCalClassificationAcc(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProCamCalibUIFigure = uifigure('Visible', 'off');
            app.ProCamCalibUIFigure.Name = 'ProCamCalib';
            app.ProCamCalibUIFigure.CloseRequestFcn = createCallbackFcn(app, @ProCamCalibUIFigureCloseRequest, true);
            app.FileMenu = uimenu(app.ProCamCalibUIFigure);
            app.FileMenu.Text = 'File';
            app.CreatenewdatadirectoryMenu = uimenu(app.FileMenu);
            app.CreatenewdatadirectoryMenu.MenuSelectedFcn = createCallbackFcn(app, @CreatenewdatadirectryCreatenewdatadirectoryMenuSelected, true);
            app.CreatenewdatadirectoryMenu.Text = 'Create new data directory';
            app.ChangedatadirectryMenu = uimenu(app.FileMenu);
            app.ChangedatadirectryMenu.MenuSelectedFcn = createCallbackFcn(app, @BrowseDataFolderButtonPushed, true);
            app.ChangedatadirectryMenu.Text = 'Change data directry';
            app.TabGroup = uitabgroup(app.ProCamCalibUIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.CaptureTab = uitab(app.TabGroup);
            app.CaptureTab.Title = 'Capture';
            app.PreviewPanelCapture = uipanel(app.CaptureTab);
            app.PreviewPanelCapture.Tooltip = {'The latest captured data will be displayed here. If you do not see color grid in Camera color grid image'; ' try increasing the exposure time in Camera Control pannel'};
            app.PreviewPanelCapture.Title = 'Projected and Captured Images';
            app.GridLayout = uigridlayout(app.PreviewPanelCapture);
            app.PrjInputImg = uiimage(app.GridLayout);
            app.CamCapPrjImg = uiimage(app.GridLayout);
            app.ProjectorInputLabel = uilabel(app.GridLayout);
            app.ProjectorInputLabel.Text = 'Projector Input';
            app.SceneLabel = uilabel(app.GridLayout);
            app.SceneLabel.Text = 'Scene';
            app.CameracapturedProjectionLabel = uilabel(app.GridLayout);
            app.CameracapturedProjectionLabel.Text = 'Camera-captured Projection';
            app.CamSceneImg = uiimage(app.GridLayout);
            app.ProjectorControlPanel = uipanel(app.CaptureTab);
            app.ProjectorControlPanel.Title = 'Projector Control';
            app.ProjectorDisplayDropDown = uidropdown(app.ProjectorControlPanel);
            app.ProjectorDisplayDropDown.Items = {};
            app.ProjectorDisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @ProjectorDisplayDropDownValueChanged, true);
            app.ProjectorDisplayDropDown.Tooltip = {'Select projector screen'; ' then click PreviewPrj'};
            app.ProjectorDisplayDropDown.Value = {};
            app.ProjectorDisplayLabel = uilabel(app.ProjectorControlPanel);
            app.ProjectorDisplayLabel.Text = 'Projector Display';
            app.BrightnessSlider_2Label = uilabel(app.ProjectorControlPanel);
            app.BrightnessSlider_2Label.Text = 'Brightness';
            app.PrjBrightnessSlider = uislider(app.ProjectorControlPanel);
            app.PrjBrightnessSlider.Limits = [0 1];
            app.PrjBrightnessSlider.MajorTicks = [0 0.5 1];
            app.PrjBrightnessSlider.MajorTickLabels = {'0', '0.5', '1'};
            app.PrjBrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @PrjBrightnessSliderValueChanged, true);
            app.PrjBrightnessSlider.MinorTicks = [];
            app.PrjBrightnessSlider.Enable = 'off';
            app.PrjBrightnessSlider.Tooltip = {'Adjust the brightness of the projected color grid. Tips: decrease the brightness according to the camera preview'};
            app.PrjBrightnessSlider.Value = 1;
            app.InputPatternTree = uitree(app.ProjectorControlPanel, 'checkbox');
            app.InputPatternTree.SelectionChangedFcn = createCallbackFcn(app, @InputPatternTreeSelectionChanged, true);
            app.gridNode = uitreenode(app.InputPatternTree);
            app.gridNode.Text = 'grid';
            app.whiteNode = uitreenode(app.InputPatternTree);
            app.whiteNode.Text = 'white';
            app.IndexSpinner = uispinner(app.ProjectorControlPanel);
            app.IndexSpinner.Limits = [1 Inf];
            app.IndexSpinner.RoundFractionalValues = 'on';
            app.IndexSpinner.ValueDisplayFormat = '%.0f';
            app.IndexSpinner.ValueChangedFcn = createCallbackFcn(app, @IndexSpinnerValueChanged, true);
            app.IndexSpinner.Tooltip = {'Index of the current projected patterns, if more than one.'};
            app.IndexSpinner.Value = 1;
            app.IndexSpinnerLabel = uilabel(app.ProjectorControlPanel);
            app.IndexSpinnerLabel.Tooltip = {''};
            app.IndexSpinnerLabel.Text = 'Index';
            app.ProjectorPreviewButton = uibutton(app.ProjectorControlPanel, 'state');
            app.ProjectorPreviewButton.ValueChangedFcn = createCallbackFcn(app, @ProjectorPreviewButtonValueChanged, true);
            app.ProjectorPreviewButton.Tooltip = {'Preview projected color grid. (The projector must be turned on and selected in the drop down on the left)'};
            app.ProjectorPreviewButton.Text = 'Preview';
            app.InputPatternLabel = uilabel(app.ProjectorControlPanel);
            app.InputPatternLabel.Text = 'Input Pattern';
            app.vertcolorsEditFieldLabel = uilabel(app.ProjectorControlPanel);
            app.vertcolorsEditFieldLabel.Text = 'vert colors';
            app.vertcolorsEditField = uieditfield(app.ProjectorControlPanel, 'numeric');
            app.vertcolorsEditField.ValueChangedFcn = createCallbackFcn(app, @vertcolorsEditFieldValueChanged, true);
            app.vertcolorsEditField.Value = 4;
            app.horicolorsEditFieldLabel = uilabel(app.ProjectorControlPanel);
            app.horicolorsEditFieldLabel.Text = 'hori colors';
            app.horicolorsEditField = uieditfield(app.ProjectorControlPanel, 'numeric');
            app.horicolorsEditField.ValueChangedFcn = createCallbackFcn(app, @horicolorsEditFieldValueChanged, true);
            app.horicolorsEditField.Value = 4;
            app.CameraControlPanel = uipanel(app.CaptureTab);
            app.CameraControlPanel.Tooltip = {'Control camera parameters. Tips: avoid camera overexposure by adjusting Exposure. Click Rest if you mess up with the parameters'};
            app.CameraControlPanel.Title = 'Camera Control';
            app.CameraNameDropDownLabel = uilabel(app.CameraControlPanel);
            app.CameraNameDropDownLabel.Text = 'Camera Name';
            app.CameraNameDropDown = uidropdown(app.CameraControlPanel);
            app.CameraNameDropDown.Items = {};
            app.CameraNameDropDown.ValueChangedFcn = createCallbackFcn(app, @CameraNameDropDownValueChanged, true);
            app.CameraNameDropDown.Tooltip = {'Select the camera to be calibrated then click PreviewCam to preview camera live frames'};
            app.CameraNameDropDown.Value = {};
            app.ResolutionDropDown = uidropdown(app.CameraControlPanel);
            app.ResolutionDropDown.Items = {};
            app.ResolutionDropDown.ValueChangedFcn = createCallbackFcn(app, @ResolutionDropDownValueChanged, true);
            app.ResolutionDropDown.Tooltip = {'List of supported resolution of your selected camera.'};
            app.ResolutionDropDown.Value = {};
            app.CamPreviewButton = uibutton(app.CameraControlPanel, 'state');
            app.CamPreviewButton.ValueChangedFcn = createCallbackFcn(app, @CamPreviewButtonValueChanged, true);
            app.CamPreviewButton.Tooltip = {'Open camera preview to adjust camera parameters in order to get a good capture. You can also click PreviewPrj to view the projected pattern in camera preview'};
            app.CamPreviewButton.Text = 'Preview';
            app.BufferSizeSpinner = uispinner(app.CameraControlPanel);
            app.BufferSizeSpinner.Limits = [0 Inf];
            app.BufferSizeSpinner.RoundFractionalValues = 'on';
            app.BufferSizeSpinner.ValueDisplayFormat = '%.0f';
            app.BufferSizeSpinner.Tooltip = {'For buffer clearing. Increase this when you see consecutive duplcate captured frames.'};
            app.BufferSizeSpinner.Value = 15;
            app.BufferSizeSpinnerLabel = uilabel(app.CameraControlPanel);
            app.BufferSizeSpinnerLabel.Tooltip = {''};
            app.BufferSizeSpinnerLabel.Text = 'Buffer Size';
            app.HeightEditField = uieditfield(app.CameraControlPanel, 'numeric');
            app.HeightEditField.Limits = [1 Inf];
            app.HeightEditField.ValueDisplayFormat = '%.0f';
            app.HeightEditField.ValueChangedFcn = createCallbackFcn(app, @HeightEditFieldValueChanged, true);
            app.HeightEditField.Tooltip = {'Final size of the saved camera-captured images in data folder.'};
            app.HeightEditField.Value = 480;
            app.HeightEditFieldLabel = uilabel(app.CameraControlPanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.WidthEditField_2Label = uilabel(app.CameraControlPanel);
            app.WidthEditField_2Label.Text = 'Width';
            app.WidthEditField = uieditfield(app.CameraControlPanel, 'numeric');
            app.WidthEditField.Limits = [1 Inf];
            app.WidthEditField.ValueDisplayFormat = '%.0f';
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Tooltip = {'Final size of the saved camera-captured images in data folder.'};
            app.WidthEditField.Value = 640;
            app.ResolutionLabel = uilabel(app.CameraControlPanel);
            app.ResolutionLabel.Text = 'Resolution';
            app.ResetButton = uibutton(app.CameraControlPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Reset camera parameters.'};
            app.ResetButton.Text = 'Reset';
            app.ContrastSliderLabel = uilabel(app.CameraControlPanel);
            app.ContrastSliderLabel.Text = 'Contrast';
            app.ContrastSlider = uislider(app.CameraControlPanel);
            app.ContrastSlider.ValueChangedFcn = createCallbackFcn(app, @CamSliderValueChanged, true);
            app.ContrastSlider.MinorTicks = [];
            app.ExposureSliderLabel = uilabel(app.CameraControlPanel);
            app.ExposureSliderLabel.Text = 'Exposure';
            app.ExposureSlider = uislider(app.CameraControlPanel);
            app.ExposureSlider.ValueChangedFcn = createCallbackFcn(app, @CamSliderValueChanged, true);
            app.ExposureSlider.MinorTicks = [];
            app.GainSliderLabel = uilabel(app.CameraControlPanel);
            app.GainSliderLabel.Text = 'Gain';
            app.GainSlider = uislider(app.CameraControlPanel);
            app.GainSlider.ValueChangedFcn = createCallbackFcn(app, @CamSliderValueChanged, true);
            app.GainSlider.MinorTicks = [];
            app.GammaSliderLabel = uilabel(app.CameraControlPanel);
            app.GammaSliderLabel.Text = 'Gamma';
            app.GammaSlider = uislider(app.CameraControlPanel);
            app.GammaSlider.ValueChangedFcn = createCallbackFcn(app, @CamSliderValueChanged, true);
            app.GammaSlider.MinorTicks = [];
            app.HueSliderLabel = uilabel(app.CameraControlPanel);
            app.HueSliderLabel.Text = 'Hue';
            app.HueSlider = uislider(app.CameraControlPanel);
            app.HueSlider.ValueChangedFcn = createCallbackFcn(app, @CamSliderValueChanged, true);
            app.HueSlider.MinorTicks = [];
            app.SaturationSliderLabel = uilabel(app.CameraControlPanel);
            app.SaturationSliderLabel.Text = 'Saturation';
            app.SaturationSlider = uislider(app.CameraControlPanel);
            app.SaturationSlider.ValueChangedFcn = createCallbackFcn(app, @CamSliderValueChanged, true);
            app.SaturationSlider.MinorTicks = [];
            app.SharpnessSliderLabel = uilabel(app.CameraControlPanel);
            app.SharpnessSliderLabel.Text = 'Sharpness';
            app.SharpnessSlider = uislider(app.CameraControlPanel);
            app.SharpnessSlider.ValueChangedFcn = createCallbackFcn(app, @CamSliderValueChanged, true);
            app.SharpnessSlider.MinorTicks = [];
            app.WhiteBalanceSliderLabel = uilabel(app.CameraControlPanel);
            app.WhiteBalanceSliderLabel.Text = 'WhiteBalance';
            app.WhiteBalanceSlider = uislider(app.CameraControlPanel);
            app.WhiteBalanceSlider.ValueChangedFcn = createCallbackFcn(app, @CamSliderValueChanged, true);
            app.WhiteBalanceSlider.MinorTicks = [];
            app.ExposureModeDropDownLabel = uilabel(app.CameraControlPanel);
            app.ExposureModeDropDownLabel.Text = 'ExposureMode';
            app.ExposureModeDropDown = uidropdown(app.CameraControlPanel);
            app.ExposureModeDropDown.Items = {};
            app.ExposureModeDropDown.ValueChangedFcn = createCallbackFcn(app, @CamControlDropDownValueChanged, true);
            app.ExposureModeDropDown.Tooltip = {'Fix to ''manual'' '};
            app.ExposureModeDropDown.Value = {};
            app.WhiteBalanceModeDropDownLabel = uilabel(app.CameraControlPanel);
            app.WhiteBalanceModeDropDownLabel.Text = 'WhiteBalanceMode';
            app.WhiteBalanceModeDropDown = uidropdown(app.CameraControlPanel);
            app.WhiteBalanceModeDropDown.Items = {};
            app.WhiteBalanceModeDropDown.ValueChangedFcn = createCallbackFcn(app, @CamControlDropDownValueChanged, true);
            app.WhiteBalanceModeDropDown.Tooltip = {'Fix to ''manual'' '};
            app.WhiteBalanceModeDropDown.Value = {};
            app.BacklightCompensationDropDownLabel = uilabel(app.CameraControlPanel);
            app.BacklightCompensationDropDownLabel.Text = 'BacklightCompensation';
            app.BacklightCompensationDropDown = uidropdown(app.CameraControlPanel);
            app.BacklightCompensationDropDown.Items = {};
            app.BacklightCompensationDropDown.ValueChangedFcn = createCallbackFcn(app, @CamControlDropDownValueChanged, true);
            app.BacklightCompensationDropDown.Tooltip = {'Fix to ''off'''};
            app.BacklightCompensationDropDown.Value = {};
            app.BrightnessSlider = uislider(app.CameraControlPanel);
            app.BrightnessSlider.ValueChangedFcn = createCallbackFcn(app, @CamSliderValueChanged, true);
            app.BrightnessSlider.MinorTicks = [];
            app.BrightnessSliderLabel = uilabel(app.CameraControlPanel);
            app.BrightnessSliderLabel.Text = 'Brightness';
            app.ExposureTimesSliderLabel = uilabel(app.CameraControlPanel);
            app.ExposureTimesSliderLabel.Text = 'Exposure Time (s)';
            app.ExposureTimesSlider = uislider(app.CameraControlPanel);
            app.ExposureTimesSlider.Limits = [0.01 2];
            app.ExposureTimesSlider.MajorTicks = [0.01 0.5 1 1.5 2];
            app.ExposureTimesSlider.ValueChangedFcn = createCallbackFcn(app, @ExposureTimesSliderValueChanged, true);
            app.ExposureTimesSlider.MinorTicks = [];
            app.ExposureTimesSlider.Tooltip = {'Increase exposure time when the camera-captured color grid image does not have color grid.'};
            app.ExposureTimesSlider.Value = 0.1;
            app.FocusModeDropDownLabel = uilabel(app.CameraControlPanel);
            app.FocusModeDropDownLabel.Text = 'FocusMode';
            app.FocusModeDropDown = uidropdown(app.CameraControlPanel);
            app.FocusModeDropDown.Items = {};
            app.FocusModeDropDown.ValueChangedFcn = createCallbackFcn(app, @CamControlDropDownValueChanged, true);
            app.FocusModeDropDown.Value = {};
            app.CaptureButton = uibutton(app.CameraControlPanel, 'state');
            app.CaptureButton.ValueChangedFcn = createCallbackFcn(app, @CaptureButtonValueChanged, true);
            app.CaptureButton.Text = 'Capture';
            app.AdaptButton = uibutton(app.CameraControlPanel, 'push');
            app.AdaptButton.ButtonPushedFcn = createCallbackFcn(app, @AdaptButtonPushed, true);
            app.AdaptButton.Text = 'Adapt';
            app.CaptureTypeButtonGroup = uibuttongroup(app.CameraControlPanel);
            app.CaptureTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CaptureTypeButtonGroupSelectionChanged, true);
            app.CaptureTypeButtonGroup.Title = 'Capture Type';
            app.CalibButton = uiradiobutton(app.CaptureTypeButtonGroup);
            app.CalibButton.Text = 'Calib';
            app.CalibButton.Value = true;
            app.ReconButton = uiradiobutton(app.CaptureTypeButtonGroup);
            app.ReconButton.Text = 'Recon';
            app.CalibrationReconstructionTab = uitab(app.TabGroup);
            app.CalibrationReconstructionTab.Title = 'Calibration & Reconstruction';
            app.CalibrationReconstructionTab.ButtonDownFcn = createCallbackFcn(app, @CalibrationReconstructionTabButtonDown, true);
            app.ReconstructionPanel = uipanel(app.CalibrationReconstructionTab);
            app.ReconstructionPanel.Title = 'Reconstruction';
            app.UseEdgePixelsCheckBox = uicheckbox(app.ReconstructionPanel);
            app.UseEdgePixelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseEdgePixelsCheckBoxValueChanged, true);
            app.UseEdgePixelsCheckBox.Tooltip = {'Also reconstruct Edge pixels.'};
            app.UseEdgePixelsCheckBox.Text = 'Use Edge Pixels';
            app.ReconVerbosedebugCheckBox = uicheckbox(app.ReconstructionPanel);
            app.ReconVerbosedebugCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReconVerbosedebugCheckBoxValueChanged, true);
            app.ReconVerbosedebugCheckBox.Tooltip = {'Check this when you want to see the intermediate results. Parallel will be disabled in debug mode.'};
            app.ReconVerbosedebugCheckBox.Text = 'Verbose (debug)';
            app.UseExistingMaskCheckBox = uicheckbox(app.ReconstructionPanel);
            app.UseExistingMaskCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseExistingMaskCheckBoxValueChanged, true);
            app.UseExistingMaskCheckBox.Tooltip = {'Use existing object ROI mask (objectROIxx.png) rather than user drawn.'};
            app.UseExistingMaskCheckBox.Text = 'Use Existing Mask';
            app.ReconstructSigleshotButton = uibutton(app.ReconstructionPanel, 'push');
            app.ReconstructSigleshotButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructSigleshotButtonPushed, true);
            app.ReconstructSigleshotButton.Tooltip = {'Start 3D-reconstruction using the calibration data and the selected set.'};
            app.ReconstructSigleshotButton.Text = 'Reconstruct Sigle-shot';
            app.EpipolarThreshpixelEditField = uieditfield(app.ReconstructionPanel, 'numeric');
            app.EpipolarThreshpixelEditField.ValueChangedFcn = createCallbackFcn(app, @EpipolarThreshpixelEditFieldValueChanged, true);
            app.EpipolarThreshpixelEditField.Tooltip = {'Epipolar distance threshold for outlier matches.'};
            app.EpipolarThreshpixelEditField.Value = 1;
            app.EpipolarThreshpixelEditFieldLabel = uilabel(app.ReconstructionPanel);
            app.EpipolarThreshpixelEditFieldLabel.Text = 'Epipolar Thresh (pixel)';
            app.ReconstructButton = uibutton(app.ReconstructionPanel, 'push');
            app.ReconstructButton.ButtonPushedFcn = createCallbackFcn(app, @ReconstructButtonPushed, true);
            app.ReconstructButton.Tooltip = {'Start 3D-reconstruction using the calibration data and the selected set.'};
            app.ReconstructButton.Text = 'Reconstruct';
            app.MinmaxContrastThreshtEditField = uieditfield(app.ReconstructionPanel, 'numeric');
            app.MinmaxContrastThreshtEditField.ValueChangedFcn = createCallbackFcn(app, @MinmaxContrastThreshtEditFieldValueChanged, true);
            app.MinmaxContrastThreshtEditField.Tooltip = {''};
            app.MinmaxContrastThreshtEditField.Value = 0.1;
            app.MinmaxContrastThreshtEditFieldLabel = uilabel(app.ReconstructionPanel);
            app.MinmaxContrastThreshtEditFieldLabel.Text = 'Min-max Contrast Thresh (t)';
            app.ProjectorbacklightstengthbEditField = uieditfield(app.ReconstructionPanel, 'numeric');
            app.ProjectorbacklightstengthbEditField.ValueChangedFcn = createCallbackFcn(app, @ProjectorbacklightstengthbEditFieldValueChanged, true);
            app.ProjectorbacklightstengthbEditField.Tooltip = {''};
            app.ProjectorbacklightstengthbEditField.Value = 0.7;
            app.ProjectorbacklightstengthbEditFieldLabel = uilabel(app.ReconstructionPanel);
            app.ProjectorbacklightstengthbEditFieldLabel.Text = 'Projector back light stength (b)';
            app.DirectLightThreshmEditField = uieditfield(app.ReconstructionPanel, 'numeric');
            app.DirectLightThreshmEditField.ValueChangedFcn = createCallbackFcn(app, @DirectLightThreshmEditFieldValueChanged, true);
            app.DirectLightThreshmEditField.Tooltip = {''};
            app.DirectLightThreshmEditField.Value = 0.02;
            app.DirectLightThreshmEditFieldLabel = uilabel(app.ReconstructionPanel);
            app.DirectLightThreshmEditFieldLabel.Text = 'Direct Light Thresh (m)';
            app.InterpolatePointCloudCheckBox = uicheckbox(app.ReconstructionPanel);
            app.InterpolatePointCloudCheckBox.Tooltip = {'Interpolate the point cloud.'};
            app.InterpolatePointCloudCheckBox.Text = 'Interpolate Point Cloud';
            app.ReconImageTree = uitree(app.ReconstructionPanel);
            app.ReconImageTree.SelectionChangedFcn = createCallbackFcn(app, @ReconImageTreeSelectionChanged, true);
            app.DeleteReconButton = uibutton(app.ReconstructionPanel, 'push');
            app.DeleteReconButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteReconButtonPushed, true);
            app.DeleteReconButton.Tooltip = {'Delete selected bad sets.'};
            app.DeleteReconButton.Text = 'Delete';
            app.CalibrationPanel = uipanel(app.CalibrationReconstructionTab);
            app.CalibrationPanel.Title = 'Calibration';
            app.CalibImageListBox = uilistbox(app.CalibrationPanel);
            app.CalibImageListBox.Items = {};
            app.CalibImageListBox.Multiselect = 'on';
            app.CalibImageListBox.ValueChangedFcn = createCallbackFcn(app, @CalibImageListBoxValueChanged, true);
            app.CalibImageListBox.Value = {};
            app.SelectAllButton = uibutton(app.CalibrationPanel, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Tooltip = {'Select all sets'};
            app.SelectAllButton.Text = 'Select All';
            app.DeleteButton = uibutton(app.CalibrationPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteButton.Tooltip = {'Delete selected bad sets.'};
            app.DeleteButton.Text = 'Delete';
            app.EstimateLensDistortionCheckBox = uicheckbox(app.CalibrationPanel);
            app.EstimateLensDistortionCheckBox.ValueChangedFcn = createCallbackFcn(app, @EstimateLensDistortionCheckBoxValueChanged, true);
            app.EstimateLensDistortionCheckBox.Tooltip = {'Estimate ProCams lens  distortions. DeProCams assumes no distortion, we may include distortion in the future.'};
            app.EstimateLensDistortionCheckBox.Text = 'Estimate Lens Distortion';
            app.EstimateLensDistortionCheckBox.Value = true;
            app.UseParallelCheckBox = uicheckbox(app.CalibrationPanel);
            app.UseParallelCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseParallelCheckBoxValueChanged, true);
            app.UseParallelCheckBox.Tooltip = {'Use MATLAB parfor to speedup calibration.'};
            app.UseParallelCheckBox.Text = 'Use Parallel';
            app.UseExistingCamCornersCheckBox = uicheckbox(app.CalibrationPanel);
            app.UseExistingCamCornersCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseExistingCamCornersCheckBoxValueChanged, true);
            app.UseExistingCamCornersCheckBox.Visible = 'off';
            app.UseExistingCamCornersCheckBox.Text = 'Use Existing Cam Corners';
            app.VerbosedebugCheckBox = uicheckbox(app.CalibrationPanel);
            app.VerbosedebugCheckBox.ValueChangedFcn = createCallbackFcn(app, @VerbosedebugCheckBoxValueChanged, true);
            app.VerbosedebugCheckBox.Tooltip = {'Check this when you want to see the intermediate results. Parallel will be disabled in debug mode.'};
            app.VerbosedebugCheckBox.Text = 'Verbose (debug)';
            app.CalibrateButton = uibutton(app.CalibrationPanel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed, true);
            app.CalibrateButton.Tooltip = {'Start pro-cam calibration based on the selected sets'};
            app.CalibrateButton.Text = 'Calibrate';
            app.CheckerboardSquareSizeEditFieldLabel_2 = uilabel(app.CalibrationPanel);
            app.CheckerboardSquareSizeEditFieldLabel_2.Text = 'mm';
            app.CheckerboardSquareSizeEditField = uieditfield(app.CalibrationPanel, 'numeric');
            app.CheckerboardSquareSizeEditField.ValueChangedFcn = createCallbackFcn(app, @CheckerboardSquareSizeEditFieldValueChanged, true);
            app.CheckerboardSquareSizeEditField.Tooltip = {'Meansure your printed checkerboard square''s size (mm) and type it here. Incorrect size will produce incorrect calibrated translation vector.'};
            app.CheckerboardSquareSizeEditField.Value = 22.5;
            app.CheckerboardSquareSizeEditFieldLabel = uilabel(app.CalibrationPanel);
            app.CheckerboardSquareSizeEditFieldLabel.Text = 'Checkerboard Square Size';
            app.LoadCalibrationButton = uibutton(app.CalibrationReconstructionTab, 'push');
            app.LoadCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadCalibrationButtonPushed, true);
            app.LoadCalibrationButton.Tooltip = {'Load existing calibration data'};
            app.LoadCalibrationButton.Text = 'Load Calibration';
            app.PreviewPanelCalibration = uipanel(app.CalibrationReconstructionTab);
            app.PreviewPanelCalibration.Title = 'Preview';
            app.PreviewImage = uiimage(app.PreviewPanelCalibration);
            app.CalibImageTypeButtonGroup = uibuttongroup(app.PreviewPanelCalibration);
            app.CalibImageTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CalibImageTypeButtonGroupSelectionChanged, true);
            app.CalibImageTypeButtonGroup.Tooltip = {'Select to preview white board and color grid images'};
            app.CalibImageTypeButtonGroup.Title = 'Calib Image Type';
            app.sceneButton = uiradiobutton(app.CalibImageTypeButtonGroup);
            app.sceneButton.Text = 'scene';
            app.colorGridButton = uiradiobutton(app.CalibImageTypeButtonGroup);
            app.colorGridButton.Text = 'colorGrid';
            app.colorGridButton.Value = true;
            app.AutoSegButton = uibutton(app.CalibImageTypeButtonGroup, 'push');
            app.AutoSegButton.ButtonPushedFcn = createCallbackFcn(app, @AutoSegButtonPushed, true);
            app.AutoSegButton.Tooltip = {'Pre-segment the *checkerboard* and color grid. If the result is bad (all black or too much background) delect (or unselect) this set, or use ManualSeg instead.'};
            app.AutoSegButton.Text = 'AutoSeg';
            app.ManualSegButton = uibutton(app.CalibImageTypeButtonGroup, 'push');
            app.ManualSegButton.ButtonPushedFcn = createCallbackFcn(app, @ManualSegButtonPushed, true);
            app.ManualSegButton.Tooltip = {'Manually segment the white board area (or objects to be reconstructed). Hold and drag your mouse to select the region of white board.'; ''; 'This will create a mask to help calibration'};
            app.ManualSegButton.Text = 'ManualSeg';
            app.CalibrationResultsTextAreaLabel = uilabel(app.CalibrationReconstructionTab);
            app.CalibrationResultsTextAreaLabel.Text = 'Calibration Results';
            app.CalibrationResultsTextArea = uitextarea(app.CalibrationReconstructionTab);
            app.CalibrationResultsTextArea.Tooltip = {'Calibration results are displayed here.'};
            app.PlotErrorsButton = uibutton(app.CalibrationReconstructionTab, 'push');
            app.PlotErrorsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotErrorsButtonPushed, true);
            app.PlotErrorsButton.Tooltip = {'Load existing calibration data'};
            app.PlotErrorsButton.Text = 'Plot Errors';
            app.PlotExtrinsicsButton = uibutton(app.CalibrationReconstructionTab, 'push');
            app.PlotExtrinsicsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotExtrinsicsButtonPushed, true);
            app.PlotExtrinsicsButton.Tooltip = {'Load existing calibration data'};
            app.PlotExtrinsicsButton.Text = 'Plot Extrinsics';
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.ConfLabel = uilabel(app.AboutTab);
            app.ConfLabel.Text = {'This software and code borrows heavily from Huang, Y. Tang, S. Ozdemir, and H. Ling. A fast and flexible'; 'projector-camera calibration system. IEEE TASE, 2020.'};
            app.GitHubHyperlink = uihyperlink(app.AboutTab);
            app.GitHubHyperlink.URL = 'https://github.com/BingyaoHuang/single-shot-pro-cam-calib';
            app.GitHubHyperlink.Text = 'GitHub';
            app.DataDirectoryLabel = uilabel(app.ProCamCalibUIFigure);
            app.DataDirectoryLabel.Text = '';
            app.DatafolderButton = uibutton(app.ProCamCalibUIFigure, 'push');
            app.DatafolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseDataFolderButtonPushed, true);
            app.DatafolderButton.Tooltip = {'Choose the captured data folder'};
            app.DatafolderButton.Text = 'Data folder';
            app.ContextMenu = uicontextmenu(app.ProCamCalibUIFigure);
            app.DeleteMenu = uimenu(app.ContextMenu);
            app.DeleteMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteMenuSelected, true);
            app.DeleteMenu.Text = 'Delete';
            % Assign app.ContextMenu
            app.CalibImageListBox.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.ProCamCalibUIFigure);
            app.ContextMenu2.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu2Opening, true);
            app.OpenMenu = uimenu(app.ContextMenu2);
            app.OpenMenu.Text = 'Open';
            % Assign app.ContextMenu2
            app.DataDirectoryLabel.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.ProCamCalibUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ProCamCalibUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProCamCalibUIFigure)
        end
    end
end